classdef PEWTR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ParticipantsListBoxLabel       matlab.ui.control.Label
        ParticipantsListBox            matlab.ui.control.ListBox
        MeanCheckBox                   matlab.ui.control.CheckBox
        RecordedTimeButton             matlab.ui.control.StateButton
        UTCButton                      matlab.ui.control.StateButton
        TimezoneEditFieldLabel         matlab.ui.control.Label
        TimezoneEditField              matlab.ui.control.EditField
        TabGroup2                      matlab.ui.container.TabGroup
        PlottingTab                    matlab.ui.container.Tab
        CropTimeTab                    matlab.ui.container.Tab
        CroptimeButton                 matlab.ui.control.StateButton
        StarttimeEditFieldLabel        matlab.ui.control.Label
        StarttimeEditField             matlab.ui.control.EditField
        EndtimeEditFieldLabel          matlab.ui.control.Label
        EndtimeEditField               matlab.ui.control.EditField
        DatatypeDropDownLabel          matlab.ui.control.Label
        DatatypeDropDown               matlab.ui.control.DropDown
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        FilterTab                      matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        FIRTab                         matlab.ui.container.Tab
        OrderEditFieldLabel            matlab.ui.control.Label
        OrderEditField                 matlab.ui.control.NumericEditField
        LowpassFreqEditFieldLabel      matlab.ui.control.Label
        LowpassFreqEditField           matlab.ui.control.NumericEditField
        LowpassFiniteImpulseResponseFIRFilterLabel_2  matlab.ui.control.Label
        SGTab                          matlab.ui.container.Tab
        PolynomialorderEditFieldLabel  matlab.ui.control.Label
        PolynomialorderEditField       matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel       matlab.ui.control.Label
        WindowsizeEditField            matlab.ui.control.NumericEditField
        SavitzkyGolaySmoothingFilterLabel  matlab.ui.control.Label
        Tab2                           matlab.ui.container.Tab
        Menu                           matlab.ui.container.Menu
        ImportrawdataMenu              matlab.ui.container.Menu
        EmpaticaformatMenu             matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: EmpaticaformatMenu
        function EmpaticaformatMenuSelected(app, event)
            sdir=uigetdir(cd,'Select Folder Containing Subjects');
            global pewtr2
            pewtr2=p_importempatica(sdir);
            p_updatedisplay(app, event);
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: ParticipantsListBox
        function ParticipantsListBoxValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: RecordedTimeButton
        function RecordedTimeButtonValueChanged(app, event)
            value = app.RecordedTimeButton.Value;
            if value==1
                app.UTCButton.Value=0;
            end
            p_updatedisplay(app, event);
        end

        % Value changed function: UTCButton
        function UTCButtonValueChanged(app, event)
            value = app.UTCButton.Value;
            if value==1
                app.RecordedTimeButton.Value=0;
            end
            p_updatedisplay(app, event);
        end

        % Value changed function: TimezoneEditField
        function TimezoneEditFieldValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: MeanCheckBox
        function MeanCheckBoxValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {589, 589, 589};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {589, 589};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x', 313};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticipantsListBoxLabel = uilabel(app.LeftPanel);
            app.ParticipantsListBoxLabel.Text = 'Participants';
            app.ParticipantsListBox = uilistbox(app.LeftPanel);
            app.ParticipantsListBox.Multiselect = 'on';
            app.ParticipantsListBox.ValueChangedFcn = createCallbackFcn(app, @ParticipantsListBoxValueChanged, true);
            app.ParticipantsListBox.Value = {'Item 1'};
            app.MeanCheckBox = uicheckbox(app.LeftPanel);
            app.MeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanCheckBoxValueChanged, true);
            app.MeanCheckBox.Text = 'Mean';
            app.RecordedTimeButton = uibutton(app.LeftPanel, 'state');
            app.RecordedTimeButton.ValueChangedFcn = createCallbackFcn(app, @RecordedTimeButtonValueChanged, true);
            app.RecordedTimeButton.Text = 'Recorded Time';
            app.RecordedTimeButton.Value = true;
            app.UTCButton = uibutton(app.LeftPanel, 'state');
            app.UTCButton.ValueChangedFcn = createCallbackFcn(app, @UTCButtonValueChanged, true);
            app.UTCButton.Text = 'UTC';
            app.TimezoneEditFieldLabel = uilabel(app.LeftPanel);
            app.TimezoneEditFieldLabel.Text = 'Timezone';
            app.TimezoneEditField = uieditfield(app.LeftPanel, 'text');
            app.TimezoneEditField.ValueChangedFcn = createCallbackFcn(app, @TimezoneEditFieldValueChanged, true);
            app.TimezoneEditField.Value = 'Australia/Brisbane';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.PlottingTab = uitab(app.TabGroup2);
            app.PlottingTab.Title = 'Plotting';
            app.CropTimeTab = uitab(app.TabGroup2);
            app.CropTimeTab.Title = 'Crop Time';
            app.CroptimeButton = uibutton(app.CropTimeTab, 'state');
            app.CroptimeButton.Text = 'Crop time';
            app.StarttimeEditFieldLabel = uilabel(app.CropTimeTab);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.CropTimeTab, 'text');
            app.EndtimeEditFieldLabel = uilabel(app.CropTimeTab);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.CropTimeTab, 'text');
            app.DatatypeDropDownLabel = uilabel(app.LeftPanel);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.LeftPanel);
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.TabGroup3 = uitabgroup(app.FilterTab);
            app.FIRTab = uitab(app.TabGroup3);
            app.FIRTab.Title = 'FIR';
            app.OrderEditFieldLabel = uilabel(app.FIRTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.FIRTab, 'numeric');
            app.LowpassFreqEditFieldLabel = uilabel(app.FIRTab);
            app.LowpassFreqEditFieldLabel.Text = 'Lowpass Freq';
            app.LowpassFreqEditField = uieditfield(app.FIRTab, 'numeric');
            app.LowpassFiniteImpulseResponseFIRFilterLabel_2 = uilabel(app.FIRTab);
            app.LowpassFiniteImpulseResponseFIRFilterLabel_2.Text = 'Low-pass Finite Impulse Response (FIR) Filter';
            app.SGTab = uitab(app.TabGroup3);
            app.SGTab.Title = 'S-G';
            app.PolynomialorderEditFieldLabel = uilabel(app.SGTab);
            app.PolynomialorderEditFieldLabel.Text = 'Polynomial order';
            app.PolynomialorderEditField = uieditfield(app.SGTab, 'numeric');
            app.WindowsizeEditFieldLabel = uilabel(app.SGTab);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.SGTab, 'numeric');
            app.SavitzkyGolaySmoothingFilterLabel = uilabel(app.SGTab);
            app.SavitzkyGolaySmoothingFilterLabel.Text = 'Savitzky-Golay Smoothing Filter';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportrawdataMenu = uimenu(app.Menu);
            app.ImportrawdataMenu.Text = 'Import raw data';
            app.EmpaticaformatMenu = uimenu(app.ImportrawdataMenu);
            app.EmpaticaformatMenu.MenuSelectedFcn = createCallbackFcn(app, @EmpaticaformatMenuSelected, true);
            app.EmpaticaformatMenu.Text = 'Empatica format';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PEWTR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end