classdef p_groups < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        SaveReturnButton           matlab.ui.control.Button
        RemoveSelectedGroupButton  matlab.ui.control.Button
        GroupListListBox           matlab.ui.control.ListBox
        GroupListListBoxLabel      matlab.ui.control.Label
        CreateNewGroupButton       matlab.ui.control.Button
        GroupLabelEditField        matlab.ui.control.EditField
        GroupLabelEditFieldLabel   matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    properties (Access = public)
        %pewtr2; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pewtrcon)
            app.CallingApp=pewtrcon;
            datatable=cell2table(pewtrcon.pewtr2.subj','VariableNames',{'Subjects'});
            app.UITable.Data=datatable;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {736, 736};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: CreateNewGroupButton
        function CreateNewGroupButtonPushed(app, event)
            vars=[app.UITable.ColumnName' {app.GroupLabelEditField.Value}];
            if length(vars)~=length(unique(vars))
               errordlg('Group labels must be unique');
               return
            end
            t=app.UITable.Data;
            t=[t array2table(zeros(height(t),1),'VariableNames',{app.GroupLabelEditField.Value})];
            app.UITable.Data=t;
            app.UITable.ColumnName=vars;
            c=ones(1,width(t));
            c(1)=0;
            c=logical(c);
            app.UITable.ColumnEditable=c;
            
            %update grouplist
            if width(t)==1
               app.GroupListListBox.Items={'No Groups Defined'};
            else
               app.GroupListListBox.Items=vars(2:end);
            end
        end

        % Button pushed function: RemoveSelectedGroupButton
        function RemoveSelectedGroupButtonPushed(app, event)
            app.GroupListListBox.ItemsData=1:length(app.GroupListListBox.Items);
            dinx=app.GroupListListBox.Value+1;
            vars=app.UITable.ColumnName; 
            t=app.UITable.Data;
            t.(vars{dinx})=[];
            vars(dinx)=[];
            app.UITable.Data=t;
            app.UITable.ColumnName=vars;
            
            %update grouplist
            if width(t)==1
               app.GroupListListBox.Items={'No Groups Defined'};
            else
               app.GroupListListBox.Items=vars(2:end);
            end
        end

        % Button pushed function: SaveReturnButton
        function SaveReturnButtonPushed(app, event)
            groupupdate(app.CallingApp,app.UITable.Data);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GroupLabelEditFieldLabel = uilabel(app.LeftPanel);
            app.GroupLabelEditFieldLabel.Text = 'Group Label';
            app.GroupLabelEditField = uieditfield(app.LeftPanel, 'text');
            app.CreateNewGroupButton = uibutton(app.LeftPanel, 'push');
            app.CreateNewGroupButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewGroupButtonPushed, true);
            app.CreateNewGroupButton.Text = 'Create New Group';
            app.GroupListListBoxLabel = uilabel(app.LeftPanel);
            app.GroupListListBoxLabel.Text = 'Group List';
            app.GroupListListBox = uilistbox(app.LeftPanel);
            app.GroupListListBox.Items = {'No Groups Defined'};
            app.GroupListListBox.Value = 'No Groups Defined';
            app.RemoveSelectedGroupButton = uibutton(app.LeftPanel, 'push');
            app.RemoveSelectedGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedGroupButtonPushed, true);
            app.RemoveSelectedGroupButton.Text = 'Remove Selected Group';
            app.SaveReturnButton = uibutton(app.LeftPanel, 'push');
            app.SaveReturnButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReturnButtonPushed, true);
            app.SaveReturnButton.Text = 'Save & Return';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Subjects'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p_groups(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end