classdef pewtranalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sochrogui_firstlevel  matlab.ui.Figure
        datapanel             matlab.ui.container.Panel
        uipanel28             matlab.ui.container.Panel
        loaddata_push         matlab.ui.control.Button
        dataset_list          matlab.ui.control.ListBox
        removedata_push       matlab.ui.control.Button
        datatypepanel         matlab.ui.container.Panel
        datatypeeda_text      matlab.ui.control.StateButton
        datatypephasic_text   matlab.ui.control.StateButton
        datatypehr_text       matlab.ui.control.StateButton
        text54                matlab.ui.control.Label
        runpush               matlab.ui.control.Button
        RMpanel               matlab.ui.container.Panel
        conditions_listbox    matlab.ui.control.ListBox
        bgfspanel             matlab.ui.container.Panel
        bgfs_listbox          matlab.ui.control.ListBox
        syncpanel             matlab.ui.container.Panel
        xcorrpanel            matlab.ui.container.Panel
        text43                matlab.ui.control.Label
        xcorr_xwin            matlab.ui.control.EditField
        xcorr_toggle          matlab.ui.control.StateButton
        xcorr_xstep           matlab.ui.control.EditField
        text56                matlab.ui.control.Label
        xcorr_nowin           matlab.ui.control.CheckBox
        xcorr_fzt             matlab.ui.control.CheckBox
        xcorr_maxlag          matlab.ui.control.EditField
        text80                matlab.ui.control.Label
        avecorrpanel          matlab.ui.container.Panel
        text2233              matlab.ui.control.Label
        slope_swin            matlab.ui.control.EditField
        slope_toggle          matlab.ui.control.StateButton
        slope_sstep           matlab.ui.control.EditField
        text57                matlab.ui.control.Label
        slope_xwin            matlab.ui.control.EditField
        text58                matlab.ui.control.Label
        slope_xstep           matlab.ui.control.EditField
        text59                matlab.ui.control.Label
        slope_maxlag          matlab.ui.control.EditField
        text81                matlab.ui.control.Label
        slope_nowin           matlab.ui.control.CheckBox
        slope_fzt             matlab.ui.control.CheckBox
        slope_noswin          matlab.ui.control.CheckBox
        dtw_panel             matlab.ui.container.Panel
        dtw_toggle            matlab.ui.control.StateButton
        dtw_lag               matlab.ui.control.EditField
        text79                matlab.ui.control.Label
        cohpanel              matlab.ui.container.Panel
        text68                matlab.ui.control.Label
        text67                matlab.ui.control.Label
        text66                matlab.ui.control.Label
        text65                matlab.ui.control.Label
        coh_f1                matlab.ui.control.EditField
        coh_f2                matlab.ui.control.EditField
        text63                matlab.ui.control.Label
        text62                matlab.ui.control.Label
        coh_win               matlab.ui.control.EditField
        coh_overlap           matlab.ui.control.EditField
        spectral_toggle       matlab.ui.control.StateButton
        text64                matlab.ui.control.Label
        uipanel33             matlab.ui.container.Panel
        dist_sm               matlab.ui.control.CheckBox
        dist_idm              matlab.ui.control.CheckBox
        dist_da               matlab.ui.control.CheckBox
        sapanel               matlab.ui.container.Panel
        uipanel25             matlab.ui.container.Panel
        text69                matlab.ui.control.Label
        psd_toggle            matlab.ui.control.StateButton
        psd_overlap           matlab.ui.control.EditField
        psd_win               matlab.ui.control.EditField
        text70                matlab.ui.control.Label
        text71                matlab.ui.control.Label
        psd_f2                matlab.ui.control.EditField
        psd_f1                matlab.ui.control.EditField
        text72                matlab.ui.control.Label
        text73                matlab.ui.control.Label
        text74                matlab.ui.control.Label
        text75                matlab.ui.control.Label
        uipanel24             matlab.ui.container.Panel
        sa_auc                matlab.ui.control.CheckBox
        sa_central            matlab.ui.control.CheckBox
        uipanel34             matlab.ui.container.Panel
        scr_toggle            matlab.ui.control.StateButton
        scr_step              matlab.ui.control.EditField
        scr_win               matlab.ui.control.EditField
        text89                matlab.ui.control.Label
        text90                matlab.ui.control.Label
        scr_nowin             matlab.ui.control.CheckBox
    end

        properties (Access = public)
        pewtr3=[]; % Data
        end
        
    methods (Access = private)
        function cohdetrendlambda_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function lambdaedit_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function sigpromaxlag_edit_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        
        function app = p_setmanage(app,input)
            if input==1 %load datasets
                filename=0; %#ok<NASGU>
                [filename, pathname] = uigetfile({'*.mat'},'MultiSelect',...
                    'on','Select file containing processed data...');
                if isequal(filename,0), return; end %if nothing selected, stop function
                temp=load([pathname filesep filename],'pewtr3'); pause(0.1);
                if ~isfield(temp,'pewtr3')
                    warndlg('No pre-processed pewtr format data found');
                    return
                end
                app.pewtr3=temp.pewtr3;
            end
            
            if input==2 %remove dataset
                app.pewtr3=[];
            end
        end
        
        function app = p_updatedisplay(app)
            if isempty(app.pewtr3) %no datasets
                set(app.dataset_list,'Items',{'No datasets...'});
                set(app.conditions_listbox,'Items',{'Entire timeseries (default)'});
                set(app.RMpanel,'Visible','off');
                set(app.runpush,'Visible','off');
                set(app.datatypepanel,'Visible','off');
                set(app.syncpanel,'Visible','off');
                set(app.sapanel,'Visible','off');
                set(app.bgfspanel,'Visible','off');
                return
            else
                set(app.RMpanel,'Visible','on');
                set(app.runpush,'Visible','on');
                set(app.datatypepanel,'Visible','on');
                set(app.syncpanel,'Visible','on');
                set(app.sapanel,'Visible','on');
                set(app.bgfspanel,'Visible','on');
            
                set(app.dataset_list,'Items',{app.pewtr3.file.sdir});
                
                condlist={};
                for kk=1:length(app.pewtr3.conditions(:,1))
                    if isempty(app.pewtr3.conditions{kk,1})
                        condlist(kk)={'Entire timeseries (default)'};
                        
                    else
                        condlist(kk)=app.pewtr3.conditions(kk,1);
                    end
                end
                app.conditions_listbox.Items=condlist;
            
                if isempty(app.pewtr3.bgfs{1,1})
                    set(app.bgfs_listbox,'Items',{'[No factors specified in data]'});
                else
                    set(app.bgfs_listbox,'Items',app.pewtr3.bgfs(:,1));
                end
            end
            
            %Highlight datatypes
            if isfield(app.pewtr3.data,'EDA')
                set(app.datatypeeda_text,'BackgroundColor',[0.85 0.85 1])
            else
                set(app.datatypeeda_text,'BackgroundColor',[1 0 0])
            end
            if isfield(app.pewtr3.data,'Phasic')
                set(app.datatypephasic_text,'BackgroundColor',[0.85 0.85 1])
            else
                set(app.datatypephasic_text,'BackgroundColor',[1 0 0])
            end
            if isfield(app.pewtr3.data,'HR')
                set(app.datatypehr_text,'BackgroundColor',[0.85 0.85 1])
            else
                set(app.datatypehr_text,'BackgroundColor',[1 0 0])
            end
        end
        
        function sover_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function swin_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function xlag_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function xwinover_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sochrogui_firstlevel_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;
            if ~isempty(varargin)
                if strcmpi(varargin{1},'interface')
                    handles=varargin{2};
                end
            else
                handles.pewtr4dir=[];
                handles.pewtr4=[];
                handles.file=[];
                handles.conditions=[];
                handles.bgfs=[];
                handles.bgfn=[];
            end
            guidata(hObject, handles);
        end

        % Value changed function: coh_f1
        function coh_f1_Callback(app, event)
            if isempty(app.coh_f1.Value)
                app.coh_f1.Value=0;
            end
        end

        % Value changed function: coh_f2
        function coh_f2_Callback(app, event)
            if isempty(app.coh_f2.Value)
                app.coh_f2.Value=0;
            end
        end

        % Value changed function: coh_overlap
        function coh_overlap_Callback(app, event)
            if isempty(app.coh_overlap.Value)
                app.coh_overlap.Value=0;
            end
        end

        % Value changed function: dtw_toggle
        function dtw_toggle_Callback(app, event)

            if app.dtw_toggle.Value==1
               app.dtw_toggle.Text='DTW Analysis - On';
            else
               app.dtw_toggle.Text='DTW Analysis - Off';
            end
        end

        % Button pushed function: loaddata_push
        function loaddata_push_Callback(app, event)

            [app]=p_setmanage(app,1);
            [app]=p_updatedisplay(app);
        end

        % Value changed function: psd_f1
        function psd_f1_Callback(app, event)
            if isempty(app.psd_f1.Value)
                app.psd_f1.Value=0;
            end
        end

        % Value changed function: psd_f2
        function psd_f2_Callback(app, event)
            if isempty(app.psd_f2.Value)
                app.psd_f2.Value=0;
            end
        end

        % Value changed function: psd_overlap
        function psd_overlap_Callback(app, event)
            if isempty(app.psd_overlap.Value)
                app.psd_overlap.Value=0;
            end
        end

        % Value changed function: psd_toggle
        function psd_toggle_Callback(app, event)
            if app.psd_toggle.Value==1
               app.psd_toggle.Text='PSD Analysis - On';
            else
               app.psd_toggle.Text='PSD Analysis - Off';
            end
            
        end

        % Value changed function: psd_win
        function psd_win_Callback(app, event)
            if isempty(app.psd_win.Value)
                app.psd_win.Value=0;
            end
        end

        % Button pushed function: removedata_push
        function removedata_push_Callback(app, event)

            [app]=p_setmanage(app,2);
            [app]=p_updatedisplay(app);
        end

        % Button pushed function: runpush
        function runpush_Callback(app, event)

            %% GET ANALYSIS PARAMETERS
            % Get datatypes
            datatypes={};
            if app.datatypeeda_text.Value==1, datatypes{end+1}='EDA'; end
            if app.datatypephasic_text.Value==1, datatypes{end+1}='PHA';
               datatypes{end+1}='TON'; end
            if app.datatypehr_text.Value==1, datatypes{end+1}='HR'; end
            if isempty(datatypes), errordlg('No datatypes selected'); 
                pause(1);
                return; 
            end
            
%             %Get windowing options
%             window={};
%             if app.hanncheck.Value==1
%                window(end+1:end+3)={'hann',str2double(get...
%                    (handles.sigprowin_edit,'String')), str2double(get...
%                    (handles.sigprowinover_edit,'String'))};
%             elseif get(handles.bartcheck,'Value')==1
%                window(end+1:end+3)={'bartlett',str2double(get...
%                    (handles.sigprowin_edit,'String')), str2double(get...
%                    (handles.sigprowinover_edit,'String'))};
%             elseif get(handles.unweightcheck,'Value')==1
%                window(end+1:end+3)={'unweight',str2double(get...
%                    (handles.sigprowin_edit,'String')), str2double(get...
%                    (handles.sigprowinover_edit,'String'))};
%             end
            
            %Get frequency analyses
            freq={};
            if app.spectral_toggle.Value==1 %Spectral coherence analysis
                f1=str2double(app.coh_f1.Value);
                f2=str2double(app.coh_f2.Value);
                if f1==0, f1=[]; end
                if f2==0, f2=[]; end
                freq(end+1:end+5)={'Coh',str2double(app.coh_win.Value), ...
                    str2double(app.coh_overlap.Value),f1,f2};
            end
            if app.psd_toggle.Value==1 %Power spectral density
                f1=str2double(app.psd_f1.Value);
                f2=str2double(app.psd_f2.Value);
                freq(end+1:end+5)={'psd',str2double(app.psd_win.Value), ...
                    str2double(app.psd_overlap.Value),f1,f2};
            end
            
            % Get timeseries analyses
            tseries={};
            if app.xcorr_toggle.Value==1 %Cross correlation
               if app.xcorr_fzt.Value==1 %check for FZT
                   param='fxcorr';
               else
                   param='xcorr';
               end
               if app.xcorr_nowin.Value==1 %No windowing
                  xwin=0; xstep=0;
               else
                   xwin=str2double(app.xcorr_xwin.Value);
                   xstep=str2double(app.xcorr_xstep.Value);
               end
               tseries(end+1:end+3)={param,xwin,xstep};
               if str2double(app.xcorr_maxlag.Value)~=0
                  tseries(end+1)={str2double(app.xcorr_maxlag.Value)};
               end
            end
            
            if app.slope_toggle.Value==1 %Average Slope correlation
                if app.slope_fzt.Value==1 %check for FZT
                    param='fslope';
                else
                    param='slope';
                end
                if app.slope_noswin.Value==1 %No slope windowing
                    swin=0; sstep=0;
                else
                    swin=str2double(app.slope_swin.Value);
                    sstep=str2double(app.slope_sstep.Value);
                end
                if app.slope_nowin.Value==1 %No corr windowing
                    xwin=0; xstep=0;
                else
                    xwin=str2double(app.slope_xwin.Value);
                    xstep=str2double(app.slope_xstep.Value);
                end
               tseries(end+1:end+5)={param,swin,sstep,xwin,xstep};
                if str2double(app.slope_maxlag.Value)~=0
                  tseries(end+1)={str2double(app.slope_maxlag.Value)};
               end
            end
            
            if app.dtw_toggle.Value==1 %Dynamic time warping
               tseries(end+1:end+2)={'dtw',str2double(app.dtw_lag.Value)};
            end
            
            if app.scr_toggle.Value==1 %SCR analysis
                if app.scr_nowin.Value==1 %No windowing
                    win=0; step=0;
                else
                    win=str2double(app.scr_win.Value);
                    step=str2double(app.scr_step.Value);
                end
               tseries(end+1:end+3)={'scr',win,step};
            end
            
            if app.sa_central.Value==1, tseries(end+1)={'central'}; end %centrality
            if app.sa_auc.Value==1, tseries(end+1)={'auc'}; end %AUC
            if app.dist_sm.Value==1, tseries(end+1)={'sm'}; end %sig match
            if app.dist_idm.Value==1, tseries(end+1)={'idm'}; end %IDM
            if app.dist_da.Value==1, tseries(end+1)={'da'}; end %DA
            
            [fname, pname] = uiputfile('*.mat','Select where to save output'); %save select
            
            %% RUN ANALYSES
                results={};
                %Get conditions in loaded dataset
                cinx=[0 (2:length(app.pewtr3.conditions(:,1)))];
            
                %% Frequency based analyses (perform before windowing)
                if ~isempty(freq)
                    [results{1}]= p_analysis(app.pewtr3,datatypes,cinx,freq{:},'progress');
                end
            
                %% Timeseries based analyses
                if ~isempty(tseries)
                    [results{2}]= p_analysis(app.pewtr3,datatypes,cinx,tseries{:},'progress');
                end
            
                 pewtr4 = p_mergefirstlvl([results{:}]);
            
            if fname~=0
            save([pname filesep fname],'pewtr4','-v7.3');
            end
            disp('Output Saved');
        end

        % Value changed function: scr_toggle
        function scr_toggle_Callback(app, event)

            if app.scr_toggle.Value==1
               app.scr_toggle.Text='SCR Analysis - On';
            else
               app.scr_toggle.Text='SCR Analysis - Off';
            end
        end

        % Value changed function: slope_sstep
        function slope_sstep_Callback(app, event)
            if isempty(app.slope_sstep.Value)
                app.slope_sstep.Value=0;
            end
        end

        % Value changed function: slope_swin
        function slope_swin_Callback(app, event)
            if isempty(app.slope_swin.Value)
                app.slope_swin.Value=0;
            end
        end

        % Value changed function: slope_toggle
        function slope_toggle_Callback(app, event)

             if app.slope_toggle.Value==1
               app.slope_toggle.Text='Average Slope Analysis - On';
            else
               app.slope_toggle.Text='Average Slope Analysis - Off';
            end
            
        end

        % Value changed function: slope_xstep
        function slope_xstep_Callback(app, event)
            if isempty(app.slope_xstep.Value)
                app.slope_xstep.Value=0;
            end
        end

        % Value changed function: slope_xwin
        function slope_xwin_Callback(app, event)
            if isempty(app.slope_xwin.Value)
                app.slope_xwin.Value=0;
            end
        end

        % Value changed function: spectral_toggle
        function spectral_toggle_Callback(app, event)

            if app.spectral_toggle.Value==1
               app.spectral_toggle.Text='Spectral Coherence Analysis - On';
            else
               app.spectral_toggle.Text='Spectral Coherence Analysis - Off';
            end
        end

        % Value changed function: xcorr_toggle
        function xcorr_toggle_Callback(app, event)
            
            if app.xcorr_toggle.Value==1
               app.xcorr_toggle.Text='Cross Correlation Analysis - On';
            else
               app.xcorr_toggle.Text='Cross Correlation Analysis - Off';
            end
        end

        % Value changed function: xcorr_xstep
        function xcorr_xstep_Callback(app, event)
            if isempty(app.xcorr_xstep.Value)
                app.xcorr_xstep.Value=0;
            end
        end

        % Value changed function: xcorr_xwin
        function xcorr_xwin_Callback(app, event)
            if isempty(app.xcorr_xwin.Value)
                app.xcorr_xwin.Value=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.sochrogui_firstlevel = uifigure('Visible', 'off');
            app.sochrogui_firstlevel.Name = 'PEWTR Analysis';
            app.sochrogui_firstlevel.Tag = 'pewtr_firstlevel';
            app.datapanel = uipanel(app.sochrogui_firstlevel);
            app.datapanel.Title = 'Datasets';
            app.datapanel.Tag = 'datapanel';
            app.uipanel28 = uipanel(app.datapanel);
            app.uipanel28.Title = 'Pre-processed dataset';
            app.uipanel28.Tag = 'uipanel28';
            app.loaddata_push = uibutton(app.uipanel28, 'push');
            app.loaddata_push.ButtonPushedFcn = createCallbackFcn(app, @loaddata_push_Callback, true);
            app.loaddata_push.Tag = 'loaddata_push';
            app.loaddata_push.Text = 'Load dataset';
            app.dataset_list = uilistbox(app.uipanel28);
            app.dataset_list.Items = {'No datasets...'};
            app.dataset_list.Multiselect = 'on';
            app.dataset_list.Tag = 'dataset_list';
            app.dataset_list.Value = {'No datasets...'};
            app.removedata_push = uibutton(app.uipanel28, 'push');
            app.removedata_push.ButtonPushedFcn = createCallbackFcn(app, @removedata_push_Callback, true);
            app.removedata_push.Tag = 'removedata_push';
            app.removedata_push.Text = 'Remove dataset';
            app.datatypepanel = uipanel(app.datapanel);
            app.datatypepanel.Title = 'Data types';
            app.datatypepanel.Tag = 'datatypepanel';
            app.datatypeeda_text = uibutton(app.datatypepanel, 'state');
            app.datatypeeda_text.Tag = 'datatypeeda_text';
            app.datatypeeda_text.Text = 'EDA';
            app.datatypephasic_text = uibutton(app.datatypepanel, 'state');
            app.datatypephasic_text.Tag = 'datatypephasic_text';
            app.datatypephasic_text.Text = 'Phasic/Tonic';
            app.datatypehr_text = uibutton(app.datatypepanel, 'state');
            app.datatypehr_text.Tag = 'datatypehr_text';
            app.datatypehr_text.Text = 'HR';
            app.text54 = uilabel(app.datatypepanel);
            app.text54.Tag = 'text54';
            app.text54.Text = 'Select data types to analyze';
            app.runpush = uibutton(app.datapanel, 'push');
            app.runpush.ButtonPushedFcn = createCallbackFcn(app, @runpush_Callback, true);
            app.runpush.Tag = 'runpush';
            app.runpush.Text = 'Run Analysis';
            app.RMpanel = uipanel(app.datapanel);
            app.RMpanel.Title = 'Repeated measures (time windows)';
            app.RMpanel.Tag = 'RMpanel';
            app.conditions_listbox = uilistbox(app.RMpanel);
            app.conditions_listbox.Items = {'Entire timeseries (default)'};
            app.conditions_listbox.Multiselect = 'on';
            app.conditions_listbox.Tag = 'conditions_listbox';
            app.conditions_listbox.Value = {'Entire timeseries (default)'};
            app.bgfspanel = uipanel(app.datapanel);
            app.bgfspanel.Title = 'Between-groups factors';
            app.bgfspanel.Tag = 'bgfspanel';
            app.bgfs_listbox = uilistbox(app.bgfspanel);
            app.bgfs_listbox.Items = {'[No factors specified in datasets]'};
            app.bgfs_listbox.Multiselect = 'on';
            app.bgfs_listbox.Tag = 'bgfs_listbox';
            app.bgfs_listbox.Value = {'[No factors specified in datasets]'};
            app.syncpanel = uipanel(app.sochrogui_firstlevel);
            app.syncpanel.Title = 'Synchrony Analysis';
            app.syncpanel.Tag = 'syncpanel';
            app.xcorrpanel = uipanel(app.syncpanel);
            app.xcorrpanel.Title = 'Cross correlation (Pearson correlation coefficient)';
            app.xcorrpanel.Tag = 'xcorrpanel';
            app.text43 = uilabel(app.xcorrpanel);
            app.text43.Tag = 'text43';
            app.text43.Text = {'Correlation window size (seconds)'; ''};
            app.xcorr_xwin = uieditfield(app.xcorrpanel, 'text');
            app.xcorr_xwin.ValueChangedFcn = createCallbackFcn(app, @xcorr_xwin_Callback, true);
            app.xcorr_xwin.Tag = 'xcorr_xwin';
            app.xcorr_xwin.Value = '0';
            app.xcorr_toggle = uibutton(app.xcorrpanel, 'state');
            app.xcorr_toggle.ValueChangedFcn = createCallbackFcn(app, @xcorr_toggle_Callback, true);
            app.xcorr_toggle.Tag = 'xcorr_toggle';
            app.xcorr_toggle.Text = 'Cross Correlation Analysis - Off';
            app.xcorr_xstep = uieditfield(app.xcorrpanel, 'text');
            app.xcorr_xstep.ValueChangedFcn = createCallbackFcn(app, @xcorr_xstep_Callback, true);
            app.xcorr_xstep.Tag = 'xcorr_xstep';
            app.xcorr_xstep.Value = '0';
            app.text56 = uilabel(app.xcorrpanel);
            app.text56.Tag = 'text56';
            app.text56.Text = {'Correlation step size (seconds)'; ''};
            app.xcorr_nowin = uicheckbox(app.xcorrpanel);
            app.xcorr_nowin.Tag = 'xcorr_nowin';
            app.xcorr_nowin.Text = 'No windowing (whole segment correlation)';
            app.xcorr_fzt = uicheckbox(app.xcorrpanel);
            app.xcorr_fzt.Tag = 'xcorr_fzt';
            app.xcorr_fzt.Text = 'Fisher''s Z-transform';
            app.xcorr_maxlag = uieditfield(app.xcorrpanel, 'text');
            app.xcorr_maxlag.Tag = 'xcorr_maxlag';
            app.xcorr_maxlag.Value = '0';
            app.text80 = uilabel(app.xcorrpanel);
            app.text80.Tag = 'text80';
            app.text80.Text = {'Max lag (seconds)'; ''};
            app.avecorrpanel = uipanel(app.syncpanel);
            app.avecorrpanel.Title = 'Average slope correlation';
            app.avecorrpanel.Tag = 'avecorrpanel';
            app.text2233 = uilabel(app.avecorrpanel);
            app.text2233.Tag = 'text2233';
            app.text2233.Text = {'Slope window size (seconds)'; ''};
            app.slope_swin = uieditfield(app.avecorrpanel, 'text');
            app.slope_swin.ValueChangedFcn = createCallbackFcn(app, @slope_swin_Callback, true);
            app.slope_swin.Tag = 'slope_swin';
            app.slope_swin.Value = '0';
            app.slope_toggle = uibutton(app.avecorrpanel, 'state');
            app.slope_toggle.ValueChangedFcn = createCallbackFcn(app, @slope_toggle_Callback, true);
            app.slope_toggle.Tag = 'slope_toggle';
            app.slope_toggle.Text = 'Average Slope Analysis - Off';
            app.slope_sstep = uieditfield(app.avecorrpanel, 'text');
            app.slope_sstep.ValueChangedFcn = createCallbackFcn(app, @slope_sstep_Callback, true);
            app.slope_sstep.Tag = 'slope_sstep';
            app.slope_sstep.Value = '0';
            app.text57 = uilabel(app.avecorrpanel);
            app.text57.Tag = 'text57';
            app.text57.Text = {'Slope step size (seconds)'; ''};
            app.slope_xwin = uieditfield(app.avecorrpanel, 'text');
            app.slope_xwin.ValueChangedFcn = createCallbackFcn(app, @slope_xwin_Callback, true);
            app.slope_xwin.Tag = 'slope_xwin';
            app.slope_xwin.Value = '0';
            app.text58 = uilabel(app.avecorrpanel);
            app.text58.Tag = 'text58';
            app.text58.Text = {'Correlation window size (seconds)'; ''};
            app.slope_xstep = uieditfield(app.avecorrpanel, 'text');
            app.slope_xstep.ValueChangedFcn = createCallbackFcn(app, @slope_xstep_Callback, true);
            app.slope_xstep.Tag = 'slope_xstep';
            app.slope_xstep.Value = '0';
            app.text59 = uilabel(app.avecorrpanel);
            app.text59.Tag = 'text59';
            app.text59.Text = {'Correlation step size (seconds)'; ''};
            app.slope_maxlag = uieditfield(app.avecorrpanel, 'text');
            app.slope_maxlag.Tag = 'slope_maxlag';
            app.slope_maxlag.Value = '0';
            app.text81 = uilabel(app.avecorrpanel);
            app.text81.Tag = 'text81';
            app.text81.Text = {'Max lag (seconds)'; ''};
            app.slope_nowin = uicheckbox(app.avecorrpanel);
            app.slope_nowin.Tag = 'slope_nowin';
            app.slope_nowin.Text = 'No windowing (whole segment correlation)';
            app.slope_fzt = uicheckbox(app.avecorrpanel);
            app.slope_fzt.Tag = 'slope_fzt';
            app.slope_fzt.Text = 'Fisher''s Z-transform';
            app.slope_noswin = uicheckbox(app.avecorrpanel);
            app.slope_noswin.Tag = 'slope_noswin';
            app.slope_noswin.Text = 'No slope windowing (sequential slopes)';
            app.dtw_panel = uipanel(app.syncpanel);
            app.dtw_panel.Title = 'Dynamic time warping (DTW)';
            app.dtw_panel.Tag = 'dtw_panel';
            app.dtw_toggle = uibutton(app.dtw_panel, 'state');
            app.dtw_toggle.ValueChangedFcn = createCallbackFcn(app, @dtw_toggle_Callback, true);
            app.dtw_toggle.Tag = 'dtw_toggle';
            app.dtw_toggle.Text = 'Dynamic Time Warping - Off';
            app.dtw_lag = uieditfield(app.dtw_panel, 'text');
            app.dtw_lag.Tag = 'dtw_lag';
            app.dtw_lag.Value = '0';
            app.text79 = uilabel(app.dtw_panel);
            app.text79.Tag = 'text79';
            app.text79.Text = {'Maximum lag (in seconds)'; ''};
            app.cohpanel = uipanel(app.syncpanel);
            app.cohpanel.Title = 'Spectral coherence';
            app.cohpanel.Tag = 'cohpanel';
            app.text68 = uilabel(app.cohpanel);
            app.text68.Tag = 'text68';
            app.text68.Text = '(Use zero for full range)';
            app.text67 = uilabel(app.cohpanel);
            app.text67.Tag = 'text67';
            app.text67.Text = 'Upper';
            app.text66 = uilabel(app.cohpanel);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Lower';
            app.text65 = uilabel(app.cohpanel);
            app.text65.Tag = 'text65';
            app.text65.Text = {'Frequency range'; ''};
            app.coh_f1 = uieditfield(app.cohpanel, 'text');
            app.coh_f1.ValueChangedFcn = createCallbackFcn(app, @coh_f1_Callback, true);
            app.coh_f1.Tag = 'coh_f1';
            app.coh_f1.Value = '0';
            app.coh_f2 = uieditfield(app.cohpanel, 'text');
            app.coh_f2.ValueChangedFcn = createCallbackFcn(app, @coh_f2_Callback, true);
            app.coh_f2.Tag = 'coh_f2';
            app.coh_f2.Value = '0';
            app.text63 = uilabel(app.cohpanel);
            app.text63.Tag = 'text63';
            app.text63.Text = {'Window overlap (seconds)'; ''};
            app.text62 = uilabel(app.cohpanel);
            app.text62.Tag = 'text62';
            app.text62.Text = {'Hanning window size (seconds)'; ''};
            app.coh_win = uieditfield(app.cohpanel, 'text');
            app.coh_win.Tag = 'coh_win';
            app.coh_win.Value = '0';
            app.coh_overlap = uieditfield(app.cohpanel, 'text');
            app.coh_overlap.ValueChangedFcn = createCallbackFcn(app, @coh_overlap_Callback, true);
            app.coh_overlap.Tag = 'coh_overlap';
            app.coh_overlap.Value = '0';
            app.spectral_toggle = uibutton(app.cohpanel, 'state');
            app.spectral_toggle.ValueChangedFcn = createCallbackFcn(app, @spectral_toggle_Callback, true);
            app.spectral_toggle.Tag = 'spectral_toggle';
            app.spectral_toggle.Text = 'Spectral Coherence Analysis - Off';
            app.text64 = uilabel(app.cohpanel);
            app.text64.Tag = 'text64';
            app.text64.Text = '-';
            app.uipanel33 = uipanel(app.syncpanel);
            app.uipanel33.Title = 'Distance measures';
            app.uipanel33.Tag = 'uipanel33';
            app.dist_sm = uicheckbox(app.uipanel33);
            app.dist_sm.Tag = 'dist_sm';
            app.dist_sm.Text = 'Signal matching';
            app.dist_idm = uicheckbox(app.uipanel33);
            app.dist_idm.Tag = 'dist_idm';
            app.dist_idm.Text = 'Instantaneous derivative matching (slope)';
            app.dist_da = uicheckbox(app.uipanel33);
            app.dist_da.Tag = 'dist_da';
            app.dist_da.Text = 'Directional agreement';
            app.sapanel = uipanel(app.sochrogui_firstlevel);
            app.sapanel.Title = 'Individual Timeseries Analysis';
            app.sapanel.Tag = 'sapanel';
            app.uipanel25 = uipanel(app.sapanel);
            app.uipanel25.Title = 'Individual power spectral density (PSD) estimate';
            app.uipanel25.Tag = 'uipanel25';
            app.text69 = uilabel(app.uipanel25);
            app.text69.Tag = 'text69';
            app.text69.Text = '-';
            app.psd_toggle = uibutton(app.uipanel25, 'state');
            app.psd_toggle.ValueChangedFcn = createCallbackFcn(app, @psd_toggle_Callback, true);
            app.psd_toggle.Tag = 'psd_toggle';
            app.psd_toggle.Text = 'PSD Analysis - Off';
            app.psd_overlap = uieditfield(app.uipanel25, 'text');
            app.psd_overlap.ValueChangedFcn = createCallbackFcn(app, @psd_overlap_Callback, true);
            app.psd_overlap.Tag = 'psd_overlap';
            app.psd_overlap.Value = '0';
            app.psd_win = uieditfield(app.uipanel25, 'text');
            app.psd_win.ValueChangedFcn = createCallbackFcn(app, @psd_win_Callback, true);
            app.psd_win.Tag = 'psd_win';
            app.psd_win.Value = '0';
            app.text70 = uilabel(app.uipanel25);
            app.text70.Tag = 'text70';
            app.text70.Text = {'Hanning window size (seconds)'; ''};
            app.text71 = uilabel(app.uipanel25);
            app.text71.Tag = 'text71';
            app.text71.Text = {'Window overlap (seconds)'; ''};
            app.psd_f2 = uieditfield(app.uipanel25, 'text');
            app.psd_f2.ValueChangedFcn = createCallbackFcn(app, @psd_f2_Callback, true);
            app.psd_f2.Tag = 'psd_f2';
            app.psd_f2.Value = '0';
            app.psd_f1 = uieditfield(app.uipanel25, 'text');
            app.psd_f1.ValueChangedFcn = createCallbackFcn(app, @psd_f1_Callback, true);
            app.psd_f1.Tag = 'psd_f1';
            app.psd_f1.Value = '0';
            app.text72 = uilabel(app.uipanel25);
            app.text72.Tag = 'text72';
            app.text72.Text = {'Frequency range'; ''};
            app.text73 = uilabel(app.uipanel25);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Lower';
            app.text74 = uilabel(app.uipanel25);
            app.text74.Tag = 'text74';
            app.text74.Text = 'Upper';
            app.text75 = uilabel(app.uipanel25);
            app.text75.Tag = 'text75';
            app.text75.Text = '(Use zero for full range)';
            app.uipanel24 = uipanel(app.sapanel);
            app.uipanel24.Title = 'Individual activity measures';
            app.uipanel24.Tag = 'uipanel24';
            app.sa_auc = uicheckbox(app.uipanel24);
            app.sa_auc.Tag = 'sa_auc';
            app.sa_auc.Text = 'Area under curve';
            app.sa_central = uicheckbox(app.uipanel24);
            app.sa_central.Tag = 'sa_central';
            app.sa_central.Text = 'Centrality (Mean/Med/Mode) and Std. Deviation';
            app.uipanel34 = uipanel(app.sapanel);
            app.uipanel34.Title = 'Skin conductance response analysis (Phasic activity only)';
            app.uipanel34.Tag = 'uipanel34';
            app.scr_toggle = uibutton(app.uipanel34, 'state');
            app.scr_toggle.ValueChangedFcn = createCallbackFcn(app, @scr_toggle_Callback, true);
            app.scr_toggle.Tag = 'scr_toggle';
            app.scr_toggle.Text = 'SCR Analysis - Off';
            app.scr_step = uieditfield(app.uipanel34, 'text');
            app.scr_step.Tag = 'scr_step';
            app.scr_step.Value = '0';
            app.scr_win = uieditfield(app.uipanel34, 'text');
            app.scr_win.Tag = 'scr_win';
            app.scr_win.Value = '0';
            app.text89 = uilabel(app.uipanel34);
            app.text89.Tag = 'text89';
            app.text89.Text = {'Moving window size (seconds)'; ''};
            app.text90 = uilabel(app.uipanel34);
            app.text90.Tag = 'text90';
            app.text90.Text = {'Window step size (seconds)'; ''};
            app.scr_nowin = uicheckbox(app.uipanel34);
            app.scr_nowin.Tag = 'scr_nowin';
            app.scr_nowin.Text = 'No windowing (peaks per sample)';
            % Show the figure after all components are created
            app.sochrogui_firstlevel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pewtranalysis(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.sochrogui_firstlevel)

                % Execute the startup function
                runStartupFcn(app, @(app)sochrogui_firstlevel_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.sochrogui_firstlevel)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sochrogui_firstlevel)
        end
    end
end