classdef pewtrcon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcessingEnvironmentforWearableTechnologyResearchUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ParticipantsListBoxLabel       matlab.ui.control.Label
        ParticipantsListBox            matlab.ui.control.ListBox
        MeanCheckBox                   matlab.ui.control.CheckBox
        DisplayOptions                 matlab.ui.container.TabGroup
        PlottingTab                    matlab.ui.container.Tab
        StarttimeEditField_2Label      matlab.ui.control.Label
        StarttimeEditPlot              matlab.ui.control.EditField
        EndtimeEditField_2Label        matlab.ui.control.Label
        EndtimeEditPlot                matlab.ui.control.EditField
        TimePlotSwitch                 matlab.ui.control.Switch
        AmpPlotSwitch                  matlab.ui.control.Switch
        AmpminLabel                    matlab.ui.control.Label
        AmpMinEditPlot                 matlab.ui.control.EditField
        AmpmaxLabel                    matlab.ui.control.Label
        AmpMaxEditPlot                 matlab.ui.control.EditField
        CropTimeTab                    matlab.ui.container.Tab
        CroptimeButton                 matlab.ui.control.StateButton
        StarttimeEditFieldLabel        matlab.ui.control.Label
        StarttimeEditCrop              matlab.ui.control.EditField
        EndtimeEditFieldLabel          matlab.ui.control.Label
        EndtimeEditCrop                matlab.ui.control.EditField
        DatatypeDropDownLabel          matlab.ui.control.Label
        DatatypeDropDown               matlab.ui.control.DropDown
        ShowLegendCheckBox             matlab.ui.control.CheckBox
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        EDAFilterTab                   matlab.ui.container.Tab
        LowpassButterworthFilterLabel  matlab.ui.control.Label
        SavitzkyGolaySmoothingFilterLabel  matlab.ui.control.Label
        OrderEditFieldLabel            matlab.ui.control.Label
        butterorder                    matlab.ui.control.NumericEditField
        LowpassFreqEditFieldLabel      matlab.ui.control.Label
        butterfreq                     matlab.ui.control.NumericEditField
        PolynomialorderEditFieldLabel  matlab.ui.control.Label
        SGorderEdit                    matlab.ui.control.NumericEditField
        WindowsizesecsEditFieldLabel   matlab.ui.control.Label
        SGwinEdit                      matlab.ui.control.NumericEditField
        BWpreview                      matlab.ui.control.StateButton
        BWapply                        matlab.ui.control.StateButton
        SGpreview                      matlab.ui.control.StateButton
        SGapply                        matlab.ui.control.StateButton
        EDAArtifactsTab                matlab.ui.container.Tab
        templabel                      matlab.ui.control.Label
        RangeCLabel                    matlab.ui.control.Label
        tempmax                        matlab.ui.control.NumericEditField
        tempmin                        matlab.ui.control.NumericEditField
        edalabel                       matlab.ui.control.Label
        edamin                         matlab.ui.control.NumericEditField
        RangeSLabel_2                  matlab.ui.control.Label
        edamax                         matlab.ui.control.NumericEditField
        MaxsignalchangeSsEditFieldLabel_2  matlab.ui.control.Label
        jumpchange                     matlab.ui.control.NumericEditField
        SlopewindowmsLabel             matlab.ui.control.Label
        jumpwin                        matlab.ui.control.NumericEditField
        jumplabel                      matlab.ui.control.Label
        translabel                     matlab.ui.control.Label
        transtime                      matlab.ui.control.NumericEditField
        TimesecsLabel                  matlab.ui.control.Label
        interlosslabel                 matlab.ui.control.Label
        interlossedit                  matlab.ui.control.NumericEditField
        interlossslabel                matlab.ui.control.Label
        tempselect                     matlab.ui.control.DropDown
        featuredist                    matlab.ui.control.Button
        tempcheck                      matlab.ui.control.StateButton
        edacheck                       matlab.ui.control.StateButton
        jumpcheck                      matlab.ui.control.StateButton
        transcheck                     matlab.ui.control.StateButton
        interlosscheck                 matlab.ui.control.StateButton
        dataloss                       matlab.ui.control.StateButton
        entropy                        matlab.ui.control.StateButton
        AlignTimeseriesTab             matlab.ui.container.Tab
        AnchorEditFieldLabel           matlab.ui.control.Label
        AnchorEditField                matlab.ui.control.EditField
        RealignrecordedtimeLabel       matlab.ui.control.Label
        FromSelectionButton            matlab.ui.control.Button
        NewAnchorEditFieldLabel        matlab.ui.control.Label
        NewAnchorEditField             matlab.ui.control.EditField
        RealignButton                  matlab.ui.control.Button
        RevertButton                   matlab.ui.control.Button
        FindACCpeaksButton             matlab.ui.control.Button
        SegmentationTab                matlab.ui.container.Tab
        ConditionsListBoxLabel         matlab.ui.control.Label
        ConditionsListBox              matlab.ui.control.ListBox
        ConditionNameEditFieldLabel    matlab.ui.control.Label
        ConditionNameEditField         matlab.ui.control.EditField
        SegmentStartEditFieldLabel     matlab.ui.control.Label
        SegmentStartEditField          matlab.ui.control.EditField
        SegmentEndEditFieldLabel       matlab.ui.control.Label
        SegmentEndEditField            matlab.ui.control.EditField
        AddSegmentButton               matlab.ui.control.Button
        RemoveSelectedConditionButton  matlab.ui.control.Button
        PlotSelectedConditionButton    matlab.ui.control.StateButton
        BetweenSubjectsFactorsTab      matlab.ui.container.Tab
        DefineGroupsButton             matlab.ui.control.Button
        GroupsListBoxLabel             matlab.ui.control.Label
        GroupsListBox                  matlab.ui.control.ListBox
        DeconvolutionTab               matlab.ui.container.Tab
        SmoothingDropDownLabel         matlab.ui.control.Label
        SmoothingDropDown              matlab.ui.control.DropDown
        InterfacewithLedalabLabel      matlab.ui.control.Label
        SmoothingwindowwidthinsamplesEditFieldLabel  matlab.ui.control.Label
        smoothwinedit                  matlab.ui.control.NumericEditField
        ledalabpush                    matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        preCDA                         matlab.ui.control.CheckBox
        postCDA                        matlab.ui.control.CheckBox
        ExportTab                      matlab.ui.container.Tab
        export                         matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        FileMenu                       matlab.ui.container.Menu
        ImportrawdataMenu              matlab.ui.container.Menu
        EmpaticaformatMenu             matlab.ui.container.Menu
        SaveworkspaceMenu              matlab.ui.container.Menu
        LoadworkspaceMenu              matlab.ui.container.Menu
        ProcessingMenu                 matlab.ui.container.Menu
        FilterMenu                     matlab.ui.container.Menu
        EDAArtifactsMenu               matlab.ui.container.Menu
        ManualArtifactsMenu            matlab.ui.container.Menu
        AlignTimeseriesMenu            matlab.ui.container.Menu
        RepeatedMeasuresMenu           matlab.ui.container.Menu
        GroupingMenu                   matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        pewtr2=[]; % Data
        dispdat=[]; %displayinformation
        disptime=[]; %displaytime
        
    end
    
    methods (Access = private)
        
        function [] = p_updatedisplay(app,~) %update display information
            disp([datestr(datetime('now')) ' - Call to update plot'])
            
            %Used only if UIAxes is removed
            if ~isvalid(app.UIAxes)
                delete(app.CenterPanel.Children);
                app.UIAxes=uiaxes(app.CenterPanel);
                app.UIAxes.Position= [app.UIAxes.Position(1:2) ...
                    (app.CenterPanel.Position(3:4) - 2*app.UIAxes.Position(1:2))];
            end
            
            app.UIAxes.YDir='normal'; %imagesc will flip this, so reset
            
            % Get Time for Chosen DataType
            displaytime=minutes(app.disptime.([app.DatatypeDropDown.Value 'mins']));
            displaytime.Format='hh:mm:ss';
            app.UIAxes.XLabel.String='Time (Recording Time)';
            
            %Display Time
            if strcmp(app.TimePlotSwitch.Value,'Default') %default timerange
                t1=1; t2=numel(displaytime);
                set(app.UIAxes,'XTickMode','auto','XTickLabelMode','auto',...
                    'YTickMode','auto','YTickLabelMode','auto');
            else
                
                [~,t1]=min(abs(displaytime-duration(app.StarttimeEditPlot.Value)));
                [~,t2]=min(abs(displaytime-duration(app.EndtimeEditPlot.Value)));
                
            end
            
            %% Get data for display
            displaydata=app.dispdat.(app.DatatypeDropDown.Value);
            displaydata=displaydata(:,app.ParticipantsListBox.Value);
            app.UIAxes.YLabel.String=[app.DatatypeDropDown.Value ' Amplitude'];
            
            %% Preview Filter Mode
            if app.SGpreview.Value==1
                [pdata,x,y] = p_filtering(displaydata,'SG',...
                    app.SGorderEdit.Value,app.SGwinEdit.Value,...
                    app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']));
                app.SGorderEdit.Value=x;
                app.SGwinEdit.Value=y;
            elseif app.BWpreview.Value==1
                [pdata] = p_filtering(displaydata,'Butter',...
                    app.butterorder.Value,app.butterfreq.Value, ...
                    app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']));
            else
                pdata=[];
            end
            
            %% Average
            if app.MeanCheckBox.Value==1
                displaydata=mean(displaydata,2,'omitnan');
                if ~isempty(pdata)
                    pdata=mean(pdata,2,'omitnan');
                end
            end
            
            %% Plot data simple
            plot(app.UIAxes,displaytime(t1:t2),displaydata(t1:t2,:));
            app.UIAxes.ColorOrder=lines;
            
            %Plotting Limits
            set(app.UIAxes,'XTickMode','auto','XTickLabelMode','auto',...
                'YTickMode','auto','YTickLabelMode','auto','XLim',...
                [displaytime(t1) displaytime(t2)]);
            
            
            if ~isempty(pdata) %preview filter data plot
                app.UIAxes.NextPlot='Add';
                plot(app.UIAxes,displaytime(t1:t2),pdata(t1:t2,:),'--r');
            end
            
            %Amp range
            if strcmp(app.AmpPlotSwitch.Value,'Custom')
                app.UIAxes.YLim=[str2double(app.AmpMinEditPlot.Value),...
                    str2double(app.AmpMaxEditPlot.Value)];
            end
            
            if app.ShowLegendCheckBox.Value==1
                if app.MeanCheckBox.Value==1
                    legend(app.UIAxes,'Mean (Selected Subjects)')
                else
                    legend(app.UIAxes,app.ParticipantsListBox.Items(app.ParticipantsListBox.Value));
                end
            else
                legend(app.UIAxes,'off')
            end
            
            %% Condition windows
            if app.PlotSelectedConditionButton.Value==1
                cc=app.ConditionsListBox.Value;
                if ~iscell(cc)
                    if cc~=1
                        for ii=2:length(app.pewtr2.conditions(cc,:)) %for each window
                            %[~,t1]=min(abs(displaytime-app.pewtr2.conditions{cc,ii}(1)));
                            %[~,t2]=min(abs(displaytime-app.pewtr2.conditions{cc,ii}(2)));
                            if isempty(app.pewtr2.conditions(cc,:))
                                break
                            end
                            t1=app.pewtr2.conditions{cc,ii}(1);
                            t2=app.pewtr2.conditions{cc,ii}(2);
                            x= ([t1 t2 t2 t1]);
                            y= [app.UIAxes.YLim(1) app.UIAxes.YLim(1) ...
                                app.UIAxes.YLim(2) app.UIAxes.YLim(2)];
                            app.UIAxes.NextPlot='Add';
                            h(ii)=fill(app.UIAxes,x,y,'red', 'facealpha',0.1, ...
                                'EdgeAlpha',.6);
                        end
                    end
                end
            end
            
            app.UIAxes.NextPlot='replacechildren';
            pewtr1=app.pewtr2;
            dispdat1=app.dispdat;
            disptime1=app.disptime;
            assignin('base','pewtr2',pewtr1);
            assignin('base','dispdat',dispdat1);
            assignin('base','disptime',disptime1);
            disp([datestr(datetime('now')) ' - Plotting complete - no errors'])
        end %end updatedisplay function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function p_displayrefresh(app,~)
            %% Fill lists
            app.dispdat=app.pewtr2.data;
            app.disptime=app.pewtr2.time;
            app.DatatypeDropDown.Items=fieldnames(app.pewtr2.data);
            app.tempselect.Items=fieldnames(app.pewtr2.data);
            app.ParticipantsListBox.Items=app.pewtr2.subj;
            app.ParticipantsListBox.ItemsData=1:numel(app.pewtr2.subj);
            %Select defaults
            app.DatatypeDropDown.Value=app.DatatypeDropDown.Items(1); %datatype
            app.ParticipantsListBox.Value=1; %participant
            app.MeanCheckBox.Value=0; %mean checkbox
            app.TimePlotSwitch.Value='Default';
            app.AmpPlotSwitch.Value='Default';
            displaytime=minutes(eval(['app.pewtr2.time.' ...
                app.DatatypeDropDown.Value 'mins']));
            displaytime.Format='hh:mm:ss';
            app.StarttimeEditPlot.Value=datestr(displaytime(1),'HH:MM:SS.FFF');
            app.EndtimeEditPlot.Value=datestr(displaytime(end),'HH:MM:SS.FFF');
            ddata=eval(['app.pewtr2.data.' app.DatatypeDropDown.Value]);
            app.AmpMinEditPlot.Value=num2str(round(min(min(ddata)),2));
            app.AmpMaxEditPlot.Value=num2str(round(max(max(ddata)),2));
            
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if isempty(dd), errordlg('No EDA data found - updating settings failed'); end

            %% Update Settings
            if ~isempty(app.pewtr2.process{dd,1})
                displaytime=minutes(app.pewtr2.time.EDAmins);
                displaytime.Format='hh:mm:ss';
                app.CroptimeButton.Value=1;
                app.StarttimeEditCrop.Value=datestr(displaytime(app.pewtr2.process{dd,1}(1)),'HH:MM:SS.FFF');
                app.EndtimeEditCrop.Value=datestr(displaytime(app.pewtr2.process{dd,1}(2)),'HH:MM:SS.FFF');
            end
            if ~isempty(app.pewtr2.process{dd,3})
                if strcmpi(app.pewtr2.process{dd,3}(1),'Butter')
                    app.BWapply.Value=1;
                    app.butterorder.Value=app.pewtr2.process{dd,3}{2};
                    app.butterfreq.Value=app.pewtr2.process{dd,3}{3};
                elseif strcmpi(app.pewtr2.process{dd,3}(1),'SG')
                    app.SGapply.Value=1;
                    app.SGorderEdit.Value=app.pewtr2.process{dd,3}{2};
                    app.SGwinEdit.Value=app.pewtr2.process{dd,3}{3};
                end
            end
            if ~isempty(app.pewtr2.process{dd,4})
               ee=find(strcmpi(D,app.pewtr2.process{dd,4}{1}));
               app.tempselect.Value=app.tempselect.Items(ee);
               app.tempmin.Value=app.pewtr2.process{dd,4}{3}(1);
               app.tempmax.Value=app.pewtr2.process{dd,4}{3}(2);
               app.tempcheck.Value=1;
            end
            if ~isempty(app.pewtr2.process{dd,5})
                app.edacheck.Value=1;
                app.edamin.Value=app.pewtr2.process{dd,5}(1);
                app.edamax.Value=app.pewtr2.process{dd,5}(2);
            end
            if ~isempty(app.pewtr2.process{dd,6})
                app.jumpcheck.Value=1;
                app.jumpwin.Value=app.pewtr2.process{dd,6}{1};
                app.jumpchange.Value=app.pewtr2.process{dd,6}{2};
            end
            if ~isempty(app.pewtr2.process{dd,7})
                app.transcheck.Value=1;
                app.transtime.Value=app.pewtr2.process{dd,7};
            end
            if ~isempty(app.pewtr2.process{dd,8})
                app.interlosscheck.Value=1;
                app.interlossedit.Value=app.pewtr2.process{dd,8};
            end
            if ~isempty(app.pewtr2.process{dd,9})
                app.RealignButton.Visible=0;
                app.RevertButton.Visible=1;
            end
            
            %% Update conditions window
            app.ConditionsListBox.Items={};
            for ii=1:length(app.pewtr2.conditions(:,1))
                if ii==1
                    app.ConditionsListBox.Items{1}='Whole session (default)';
                else
                    wins=sum(~cellfun(@isempty, app.pewtr2.conditions(ii,2:end)));
                    app.ConditionsListBox.Items=[app.ConditionsListBox.Items...
                        {[app.pewtr2.conditions{ii,1}...
                        ' (' num2str(wins) ' segments)']}];
                end
            end
            app.ConditionsListBox.ItemsData=1:ii;
            
            %% Update groups window
            if length(app.pewtr2.bgfs)~=1
                app.GroupsListBox.Items=app.pewtr2.bgfs(:,1);
            else
                app.GroupsListBox.Items={'None Defined'};
            end


        end %end display refresh function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function p_preprocessdisp(app) %Run preprocessing steps on displaydat
            D=app.DatatypeDropDown.Items; %datatypes
            app.dispdat=app.pewtr2.data; %reload original data
            app.disptime=app.pewtr2.time; %reload original timeseries
            
            % 1 - Crop Time / 2 - Remove Subjects
            for dd=1:numel(D) %apply to each datatype
                if ~isempty(app.pewtr2.process{dd,1})
                    disp([datestr(datetime('now')) ' - Cropping data'])
                    t=app.pewtr2.process{dd,1}; %get crop times
                    app.disptime.([D{dd} 'mins'])=app.pewtr2.time.([D{dd} 'mins'])(t(1):t(2));
                    app.disptime.(D{dd})=app.pewtr2.time.(D{dd})(t(1):t(2));
                    app.dispdat.(D{dd})=app.dispdat.(D{dd})(t(1):t(2),:);
                end
                if ~isempty(app.pewtr2.process{dd,2}) %get subject removal
                    app.dispdat.(D{dd})(:,app.pewtr2.process{dd,2})=NaN; %remove subjects
                end
            end
            
            % 9 - Time realignment
            for dd=1:numel(D) %apply to each datatype
                if ~isempty(app.pewtr2.process{dd,9})
                    disp([datestr(datetime('now')) ' - Realigning recorded time'])
                    app.disptime.([D{dd} 'mins'])=app.disptime.([D{dd} 'mins'])...
                        -app.pewtr2.process{dd,9};
                end
            end

            % 4 - temp range
            if sum(~cellfun(@isempty,app.pewtr2.process(:,4)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: TEMP range'])
                for    dd=find(~cellfun(@isempty,app.pewtr2.process(:,4)))'
                    ee=strcmpi(app.pewtr2.process{dd,4}{1},D);
                    [dinx]=p_artifact(app.dispdat.(D{dd}),...
                        app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                        'temp',app.dispdat.(D{ee}),...
                        app.pewtr2.process{dd,4}{2}, ...
                        app.pewtr2.process{dd,4}{3});
                    app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
                end
            end
            % 5 - EDA range
            if sum(~cellfun(@isempty,app.pewtr2.process(:,5)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: EDA range'])
              for  dd=find(~cellfun(@isempty,app.pewtr2.process(:,5)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'range',app.pewtr2.process{dd,5});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
              end
            end
            % 6 - jump artifacts
            if sum(~cellfun(@isempty,app.pewtr2.process(:,6)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: Jumps'])
             for   dd=find(~cellfun(@isempty,app.pewtr2.process(:,6)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'jump',app.pewtr2.process{dd,6}{:});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
             end
            end
            % 7 - transitional period
            if sum(~cellfun(@isempty,app.pewtr2.process(:,7)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: Transition periods'])
              for  dd=find(~cellfun(@isempty,app.pewtr2.process(:,7)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'trans',app.pewtr2.process{dd,7});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
              end
            end
            % 8 - interloss period
            if sum(~cellfun(@isempty,app.pewtr2.process(:,8)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: Interloss periods'])
              for  dd=find(~cellfun(@isempty,app.pewtr2.process(:,8)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'interloss',app.pewtr2.process{dd,8});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
              end
            end
            % 3 - Filter Data
            if sum(~cellfun(@isempty,app.pewtr2.process(:,3)))~=0 %find filter settings
                disp([datestr(datetime('now')) ' - Applying Filters'])
                for dd=find(~cellfun(@isempty,app.pewtr2.process(:,3)))'
                    [app.dispdat.(D{dd}),app.pewtr2.process{dd,3}{2},...
                        app.pewtr2.process{dd,3}{3}]=...
                        p_filtering(app.dispdat.(D{dd}),...
                        app.pewtr2.process{dd,3}{:}, ...
                        app.pewtr2.file.Hz.([D{dd} 'hz']));
                end
            end
            
            disp([datestr(datetime('now')) ' - Preprocessing complete'])
        end %end p_preprocessdisp
        
        function [datout,timeout,timeoutmins,datsetout] = p_process(app,...
                datsource,dattime,dattimemins,datset,Hz,tempx)
        
        % 1 - Crop Time / 2 - Remove Subjects
        if ~isempty(datset{1})
            disp([datestr(datetime('now')) ' - Cropping data'])
            t=datset{1}; %get crop inx
            dattime=dattime(t(1):t(2));
            dattimemins=dattimemins(t(1):t(2));
            datsource=datsource(t(1):t(2),:);
            
            if ~isempty(tempx)
                tempx=tempx(t(1):t(2),:);
            end
        end
        
        if ~isempty(datset{2}) %get subject removal
            datsource(:,datsource{2})=NaN; %remove subjects
        end
        
        % 9 - Time realignment
        if ~isempty(datset{9})
            disp([datestr(datetime('now')) ' - Realigning recorded time'])
            dattimemins=dattimemins-datset{9};
        end
        
        % 4 - temp range
        if ~isempty(datset{4}) %find settings
            disp([datestr(datetime('now')) ' - Remove artifacts: TEMP range'])
            [dinx]=p_artifact(datsource,Hz,'temp',tempx,...
                datset{4}{2},datset{4}{3});
            datsource(logical(sum(dinx,3)))=NaN;
        end
        
        % 5 - EDA range
        if ~isempty(datset{5}) %find settings
            disp([datestr(datetime('now')) ' - Remove artifacts: EDA range'])
            [dinx]=p_artifact(datsource,Hz,'range',datset{5});
            datsource(logical(sum(dinx,3)))=NaN;
        end
        
        % 6 - jump artifacts
        if ~isempty(datset{6}) %find settings
            disp([datestr(datetime('now')) ' - Remove artifacts: Jumps'])
            [dinx]=p_artifact(datsource,Hz,'jump',datset{6}{:});
            datsource(logical(sum(dinx,3)))=NaN;
        end
        
        % 7 - transitional period
        if ~isempty(datset{7}) %find settings
            disp([datestr(datetime('now')) ' - Remove artifacts: Transition periods'])
            [dinx]=p_artifact(datsource,Hz,'trans',datset{7});
            datsource(logical(sum(dinx,3)))=NaN;
        end
        
        % 8 - interloss period
        if ~isempty(datset{8}) %find settings
            disp([datestr(datetime('now')) ' - Remove artifacts: Interloss periods'])
            [dinx]=p_artifact(datsource,Hz,'interloss',datset{8});
            datsource(logical(sum(dinx,3)))=NaN;
        end
        
        % 3 - Filter Data
        if ~isempty(datset{3}) %find filter settings
            disp([datestr(datetime('now')) ' - Applying Filters'])
            [datsource,datset{3}{2},datset{3}{3}]=...
                p_filtering(datsource,datset{3}{:},Hz);
        end
        
        disp([datestr(datetime('now')) ' - Preprocessing complete'])
        
        datout=datsource;
        timeout=dattime;
        timeoutmins=dattimemins;
        datsetout=datset;
        end %end p_process
        
    end %end private methods
    
    methods (Access = public)
        
        function groupupdate(app,t)
            app.pewtr2.bgfs=cell(1);
            vars=t.Properties.VariableNames;
            for ii=2:width(t) %for each group
                app.pewtr2.bgfs(ii-1,1)=vars(ii); %save name
                app.pewtr2.bgfs(ii-1,2)={table2array(t(:,ii))}; %save ind
            end
            
            if length(vars)>1
                app.GroupsListBox.Items=vars(2:end);
            else
                app.GroupsListBox.Items={'None Defined'};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: EmpaticaformatMenu
        function EmpaticaformatMenuSelected(app, event)
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='off';
            sdir=uigetdir(cd,'Select Folder Containing Subjects');
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='on';
            if ~isempty(sdir)
                app.pewtr2=p_importempatica(sdir);
                app.dispdat=app.pewtr2.data;
                app.disptime=app.pewtr2.time;
                p_displayrefresh(app,event);
                p_updatedisplay(app, event);
            end
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: ParticipantsListBox
        function ParticipantsListBoxValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: MeanCheckBox
        function MeanCheckBoxValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: CroptimeButton
        function CroptimeButtonValueChanged(app, event)
            value = app.CroptimeButton.Value;
            D=app.DatatypeDropDown.Items;
            for dd=1:numel(D)
                if value==1 %crop time values
                    temp=minutes(app.pewtr2.time.([D{dd} 'mins']));
                    [~,app.pewtr2.process{dd,1}(1)]...
                        =min(abs(temp-duration(app.StarttimeEditCrop.Value)));
                    [~,app.pewtr2.process{dd,1}(2)]...
                        =min(abs(temp-duration(app.EndtimeEditCrop.Value)));
                    app.StarttimeEditPlot.Value=app.StarttimeEditCrop.Value;
                    app.EndtimeEditPlot.Value=app.EndtimeEditCrop.Value;
                    
                else %if 'uncropping'
                    app.pewtr2.process{dd,1}=[];
                end
            end %end dd cycle
            p_preprocessdisp(app);
            p_updatedisplay(app, event);
        end

        % Value changed function: TimePlotSwitch
        function TimePlotSwitchValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: AmpPlotSwitch
        function AmpPlotSwitchValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: SGpreview
        function SGpreviewValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                app.DatatypeDropDown.Value='EDA';
                p_updatedisplay(app);
            end
        end

        % Value changed function: SGapply
        function SGapplyValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                app.DatatypeDropDown.Value='EDA';
                if app.SGapply.Value==1 %turned on
                    app.pewtr2.process{dt,3}={'SG',app.SGorderEdit.Value,...
                        app.SGwinEdit.Value};
                    
                    %switch off other filters
                    app.BWapply.Value=0;
                    
                else %turned off
                    app.pewtr2.process{dt,3}=[];
                    
                end
                p_preprocessdisp(app);
                p_updatedisplay(app);
            end
        end

        % Value changed function: BWpreview
        function BWpreviewValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                app.DatatypeDropDown.Value='EDA';
                p_updatedisplay(app);
            end
        end

        % Button pushed function: featuredist
        function featuredistButtonPushed(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            featfig=subplot(2,2,1);
            histogram(featfig,app.dispdat.(D{dd})(:),20);
            title('EDA');
            xlabel('EDA (μS)');
            ylabel('Occurences');
            
            featfig=subplot(2,2,2);
            ds=app.pewtr2.file.Hz.([D{dd} 'hz'])*...
                (app.jumpwin.Value/1000); %window size in data samples calculation
            if ceil(ds)~=ds
                errordlg(['Selected window length for slope calculation is too large for' ...
                    ' sampling rate. Window length adjusted to ' ...
                    num2str(ceil(ds)/app.pewtr2.file.Hz.([D{dd} 'hz'])) ...
                    'ms (' num2str(ceil(ds)) ' data points)'],'Artifact Eval Warning');
                ds=ceil(ds);
                app.jumpwin.Value=(ds/app.pewtr2.file.Hz.([D{dd} 'hz']))*1000;
            end
            data=app.dispdat.(D{dd});
            jcount=zeros(size(data));
            jcount(end-ds+1:end,:)=[];
            for ii=1:numel(data(1,:)) %for each subject
                for jj=1:numel(jcount(:,ii)) %for each datapoint
                    jcount(jj,ii)=mean(abs(data(jj,ii)-data(jj+1:jj+ds,ii)),'omitnan');
                end
            end
            histogram(featfig,jcount(:),20);
            title('Jump Artifacts');
            xlabel('EDA Slope (μS/sec)');
            ylabel('Occurences');
            
            
            featfig=subplot(2,2,3);
            ee=strcmpi(D,app.tempselect.Value);
            histogram(featfig,app.dispdat.(D{ee})(:),20);
            title('Temperature');
            xlabel('Temperature (°C)');
            ylabel('Occurences');
            
            featfig=subplot(2,2,4);
            ff=strcmpi(D,'acc');
            histogram(featfig,app.dispdat.(D{ff})(:),20);
            title('Accelerometer');
            xlabel('Acceleration (ENMO)');
            ylabel('Occurences');
            
        end

        % Value changed function: tempselect
        function tempselectValueChanged(app, event)
            if app.tempcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: tempcheck
        function tempcheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.tempcheck.Value=0;
                return
            end
            if app.tempcheck.Value==1
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                app.tempcheck.Text='On';
                app.tempcheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,4}=[];
                app.tempcheck.Text='Off';
                app.tempcheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocessdisp(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: edacheck
        function edacheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.edacheck.Value=0;
                return
            end
            if app.edacheck.Value==1
                app.pewtr2.process(dd,5)={...
                    [app.edamin.Value,app.edamax.Value]};
                app.edacheck.Text='On';
                app.edacheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,5}=[];
                app.edacheck.Text='Off';
                app.edacheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocessdisp(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: jumpcheck
        function jumpcheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.jumpcheck.Value=0;
                return
            end
            if app.jumpcheck.Value==1
                app.pewtr2.process{dd,6}={...
                    app.jumpwin.Value,app.jumpchange.Value};
                app.jumpcheck.Text='On';
                app.jumpcheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,6}=[];
                app.jumpcheck.Text='Off';
                app.jumpcheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocessdisp(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: transcheck
        function transcheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.transcheck.Value=0;
                return
            end
            if app.transcheck.Value==1
                app.pewtr2.process(dd,7)={app.transtime.Value};
                app.transcheck.Text='On';
                app.transcheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,7}=[];
                app.transcheck.Text='Off';
                app.transcheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocessdisp(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: interlosscheck
        function interlosscheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.interlosscheck.Value=0;
                return
            end
            if app.interlosscheck.Value==1
                app.pewtr2.process(dd,8)={app.interlossedit.Value};
                app.interlosscheck.Text='On';
                app.interlosscheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,8}=[];
                app.interlosscheck.Text='Off';
                app.interlosscheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocessdisp(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            p_updatedisplay(app);
        end

        % Value changed function: tempmin
        function tempminValueChanged(app, event)
            if app.tempcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: tempmax
        function tempmaxValueChanged(app, event)
            if app.tempcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: edamin
        function edaminValueChanged(app, event)
            if app.edacheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,5)={...
                    [app.edamin.Value,app.edamax.Value]};
                app.edacheck.Text='On';
                app.edacheck.BackgroundColor=[0.95,0.95,1];
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: edamax
        function edamaxValueChanged(app, event)
            if app.edacheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,5)={...
                    [app.edamin.Value,app.edamax.Value]};
                app.edacheck.Text='On';
                app.edacheck.BackgroundColor=[0.95,0.95,1];
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: jumpwin
        function jumpwinValueChanged(app, event)
            if app.jumpcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process{dd,6}={...
                    app.jumpwin.Value,app.jumpchange.Value};
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: jumpchange
        function jumpchangeValueChanged(app, event)
            if app.jumpcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process{dd,6}={...
                    app.jumpwin.Value,app.jumpchange.Value};
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: transtime
        function transtimeValueChanged(app, event)
            if app.transcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,7)={app.transtime.Value};
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: interlossedit
        function interlosseditValueChanged(app, event)
            if app.interlosscheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,8)={app.interlossedit.Value};
                p_preprocessdisp(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: dataloss
        function datalossValueChanged(app, event)
            if app.dataloss.Value==1
                app.entropy.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                urdata=app.pewtr2.data.(D{dd});
                if ~isempty(app.pewtr2.process{dd,1}) %crop times
                    t=app.pewtr2.process{dd,1}; %get crop times
                    urdata=urdata(t(1):t(2),:);
                end
                if ~isempty(app.pewtr2.process{dd,2}) %subject removal
                    urdata(:,app.pewtr2.process{dd,2})=NaN; %remove subjects
                end
                [row,~]=size(urdata);
                E2=sum(~isnan(app.dispdat.(D{dd})))/row;
                E1=(sum(~isnan(urdata))/row)-E2;
                
                bar(app.UIAxes,categorical(app.ParticipantsListBox.Items),...
                    [E2'.*100,E1'.*100],'stacked');
                app.UIAxes.YDir='normal';
                ylabel(app.UIAxes,'Data Present (%)');
                xlabel(app.UIAxes,'Participants');
                legend(app.UIAxes,'Post Artifact removal',...
                    'Pre Artifact Removal');
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: entropy
        function entropyValueChanged(app, event)
            if app.entropy.Value==1
                app.dataloss.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                urdata=app.pewtr2.data.(D{dd});
                hz=app.pewtr2.file.Hz.([D{dd} 'hz']);
                if ~isempty(app.pewtr2.process{dd,1}) %crop times
                    t=app.pewtr2.process{dd,1}; %get crop times
                    urdata=urdata(t(1):t(2),:);
                end
                if ~isempty(app.pewtr2.process{dd,2}) %subject removal
                    urdata(:,app.pewtr2.process{dd,2})=NaN; %remove subjects
                end
                if sum(~cellfun(@isempty,app.pewtr2.process(:,3)))~=0 %filter
                    [urdata]=p_filtering(urdata,...
                        app.pewtr2.process{dd,3}{:},hz);
                end
                urdata=normalize(urdata);
                [row,col]=size(urdata);
                E1=NaN(size(urdata));
                for ii=1:col
                    for jj=1:10*hz:(row-rem(row,10*hz))
                        E1(jj,ii)=wentropy(urdata(jj:jj+10*hz,ii),'shannon');
                    end
                end
                data=normalize(app.dispdat.(D{dd}));
                [row,col]=size(data);
                E2=NaN(size(data));
                for ii=1:col
                    for jj=1:10*hz:(row-rem(row,10*hz))
                        E2(jj,ii)=wentropy(data(jj:jj+10*hz,ii),'shannon');
                    end
                end
                E1=mean(E1,'omitnan');
                E2=mean(E2,'omitnan');
                
                bar(app.UIAxes,categorical(app.ParticipantsListBox.Items),...
                    [E1;E2]);
                app.UIAxes.YDir='reverse';
                ylabel(app.UIAxes,'Shannon Entropy');
                xlabel(app.UIAxes,'Participants');
                legend(app.UIAxes,'Pre Artifact removal',...
                    'Post Artifact Removal');
            else
                p_updatedisplay(app);
            end
            
        end

        % Menu selected function: FilterMenu
        function FilterMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.EDAFilterTab;
        end

        % Menu selected function: EDAArtifactsMenu
        function EDAArtifactsMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.EDAArtifactsTab;
        end

        % Menu selected function: ManualArtifactsMenu
        function ManualArtifactsMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.ManualArtifactsTab;
        end

        % Menu selected function: AlignTimeseriesMenu
        function AlignTimeseriesMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.AlignTimeseriesTab;
        end

        % Menu selected function: RepeatedMeasuresMenu
        function RepeatedMeasuresMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.SegmentationTab;
        end

        % Menu selected function: GroupingMenu
        function GroupingMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.BetweenSubjectsFactorsTab;
        end

        % Button pushed function: ledalabpush
        function ledalabpushButtonPushed(app, event)
            
            %% Initial checks and variables
            if ~exist('Ledalab','file')
                [~] = questdlg(['ERROR: Ledalab could not be found on the MATLAB ' ...
                    'path. Please ensure Ledalab (www.ledalab.de) is installed and all subdirectories are ' ...
                    'added to the matlab path.'],'Ledalab not found','Okay','Okay');
                disp('Continuous decomposition analysis cancelled - Ledalab not found on path');
                return
            end
            
            saveopt=questdlg(['CDA is time consuming. Would you like to ' ...
                'automatically save the workspace upon completion?'], ...
                'Save on completion?', 'Yes', 'No','Yes');
            
            if strcmp(saveopt,'Yes')
                [filename,pathname,~]=uiputfile('*.mat','Save workspace as');
            end
            
            pause(1)
            begindir=cd; %get current directory
            
            %% Make temp directory
            [success,~,~]=mkdir(begindir,'sochro_temp_mat');
            if success==0
                errordlg(['Error: temporary file creation for CDA ' ...
                    'failed. Check permissions for the current directory'],'ERROR');
                return
            end
            
            %% Allocate data to temporary files
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            EDA=app.pewtr2.data.EDA; %copy EDA data
            
            %Apply preprocessing
            if app.preCDA.Value==1 %if preprocess before CDA
                if ~isempty(app.pewtr2.process{dd,4}) %if temp exclusion
                    tt=strcmpi(app.pewtr2.process{dd,4}{1},...
                        fieldnames(app.pewtr2.data)); % find temp inx
                    [EDA,timeout,timeoutmins,datsetout] = p_process(app,...
                        EDA,app.pewtr2.time.EDA,app.pewtr2.time.EDAmins,...
                        app.pewtr2.process(dd,:),app.pewtr2.file.Hz.EDAhz,...
                        app.pewtr2.data.(D{tt}));
                else
                    [EDA,timeout,timeoutmins,datsetout] = p_process(app,...
                        EDA,app.pewtr2.time.EDA,app.pewtr2.time.EDAmins,...
                        app.pewtr2.process(dd,:),app.pewtr2.file.Hz.EDAhz,...
                        []);
                end
            else
                %Apply filters
                if app.SGapply.Value==1
                    [EDA,x,y] = p_filtering(EDA,'SG',...
                        app.SGorderEdit.Value,app.SGwinEdit.Value,...
                        app.pewtr2.file.Hz.EDAhz);
                    app.SGorderEdit.Value=x;
                    app.SGwinEdit.Value=y;
                    disp('SG filter applied');
                elseif app.BWapply.Value==1
                    [EDA] = p_filtering(EDA,'Butter',...
                        app.butterorder.Value,app.butterfreq.Value, ...
                        app.pewtr2.file.Hz.EDAhz);
                    disp('Butterworth filter applied');
                end
            end
            
            
            Hz=app.pewtr2.file.Hz.EDAhz; %get frequency
            inx=NaN(length(EDA(1,:)),2); %empty indices
            ledalist=1:length(EDA(1,:)); %number of ledalab files
            for ii=1:length(EDA(1,:)) %for each EDA timeseries
                if isempty(find(~isnan(EDA(:,ii)),1,'first')) %check for empty data
                    ledalist(ii)=0; %mark for skipping
                    continue
                end
                inx(ii,1)=find(~isnan(EDA(:,ii)),1,'first'); %get beginning of time series
                inx(ii,2)=find(~isnan(EDA(:,ii)),1,'last'); %get end of timeseries
                data = []; %empty data for this subject
                data.conductance=EDA(inx(ii,1):inx(ii,2),ii); %load EDA data in
                data.conductance(isnan(data.conductance))=0; %replace NaNs with zeros
                data.time=0:1/Hz:(length(data.conductance)/Hz)-1/Hz; %Make timeline
                data.timeoff=0;
                data.event=[];
                save([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '.mat'],'data'); %save timeseries
            end
            
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='off';
            %                 try
            %LEDALAB BATCHING for different smoothing options
            switch app.SmoothingDropDown.Value
                case 'None'
                    for ii=1:length(EDA(1,:))
                        if ledalist(ii)~=0
                            disp(['RUNNING CDA - DATASET ' num2str(ii)]);
                            Ledalab([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '.mat'] ,...
                                'open','mat','analyze','CDA','optimize',4,'export_scrlist',[.05 1])
                        end
                    end
                    
                case 'Moving average'
                    for ii=1:length(EDA(1,:))
                        if ledalist(ii)~=0
                            disp(['RUNNING CDA - DATASET ' num2str(ii)]);
                            Ledalab([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '.mat'] ,...
                                'open','mat','smooth',{'mean',app.smoothwinedit},...
                                'analyze','CDA','optimize',4,'export_scrlist',[.05 1])
                        end
                    end
                    
                case 'Hanning window'
                    for ii=1:length(EDA(1,:))
                        if ledalist(ii)~=0
                            disp(['RUNNING CDA - DATASET ' num2str(ii)]);
                            Ledalab([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '.mat'] ,...
                                'open','mat','smooth',{'hann',app.smoothwinedit},...
                                'analyze','CDA','optimize',4,'export_scrlist',[.05 1])
                        end
                    end
                    
                case 'Gauss window'
                    for ii=1:length(EDA(1,:))
                        if ledalist(ii)~=0
                            disp(['RUNNING CDA - DATASET ' num2str(ii)]);
                            Ledalab([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '.mat'] ,...
                                'open','mat','smooth',{'gauss',app.smoothwinedit},...
                                'analyze','CDA','optimize',4,'export_scrlist',[.05 1])
                        end
                    end
                    
                case 'Adaptive Smoothing'
                    for ii=1:length(EDA(1,:))
                        if ledalist(ii)~=0
                            disp(['RUNNING CDA - DATASET ' num2str(ii)]);
                            Ledalab([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '.mat'] ,...
                                'open','mat','smooth',{'adapt'},...
                                'analyze','CDA','optimize',4,'export_scrlist',[.05 1])
                        end
                    end
            end
            
            %% Return data
            cd(begindir);
            tonic=NaN(size(EDA));
            phasic=NaN(size(EDA));
            
            PR=struct;
            for ii=1:length(EDA(1,:))
                if ledalist(ii)==0
                    continue
                end
                load([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '.mat'],'analysis');
                tonic(inx(ii,1):inx(ii,2),ii)=analysis.tonicData';
                phasic(inx(ii,1):inx(ii,2),ii)=analysis.phasicData';
                ix=tonic(:,ii)<0;
                tonic(ix,ii)=NaN;
                ix=phasic(:,ii)<0;
                phasic(ix,ii)=NaN;
                ix=find(isnan(EDA(:,ii)));
                tonic(ix,ii)=NaN;
                phasic(ix,ii)=NaN;
                
                load([begindir filesep 'sochro_temp_mat' filesep num2str(ii) '_scrlist.mat'],'scrList');
                PR(ii).CDA=scrList.CDA;
                PR(ii).TTP=scrList.TTP;
            end
            
            %Remove artifact post CDA
            if app.postCDA.Value==1 %if remove artifacts after CDA
                EDA=app.pewtr2.data.EDA; %copy EDA data
                [EDA,timeout,timeoutmins,datsetout] = p_process(app,...
                    EDA,app.pewtr2.time.EDA,app.pewtr2.time.EDAmins,...
                    app.pewtr2.process(dd,:),app.pewtr2.file.Hz.EDAhz,...
                    []);
                for ii=1:length(EDA(1,:))
                    ix=EDA(:,ii)<=0;
                    tonic(ix,ii)=NaN;
                    phasic(ix,ii)=NaN;
                end
                ix=isnan(EDA);
                tonic(ix)=NaN;
                phasic(ix)=NaN;
            end
            
            %Update fields
            app.pewtr2.data.Phasic=phasic;
            app.pewtr2.data.Tonic=tonic;
            app.pewtr2.SCR=PR;
            app.pewtr2.time.Phasic=timeout;
            app.pewtr2.time.Tonic=timeout;
            app.pewtr2.time.Phasicmins=timeoutmins;
            app.pewtr2.time.Tonicmins=timeoutmins;
            app.pewtr2.file.Hz.Phasichz=app.pewtr2.file.Hz.EDAhz;
            app.pewtr2.file.Hz.Tonichz=app.pewtr2.file.Hz.EDAhz;
            app.pewtr2.process(end+1:end+2,:)=cell(2,length(app.pewtr2.process(1,:)));
            
            
            %%Put processing steps in slot ten so theyre not
            %%reapplied
            
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if app.preCDA.Value==1 %if remove artifacts before CDA
                app.pewtr2.process(end,10)={datsetout};
                app.pewtr2.process(end-1,10)={datsetout};
            else
                app.pewtr2.process(end-1:end,1)=app.pewtr2.process(dd,1);
                app.pewtr2.process(end-1:end,2)=app.pewtr2.process(dd,2);
                app.pewtr2.process(end-1:end,9)=app.pewtr2.process(dd,9);
            end
            
            try
                rmdir([begindir filesep 'sochro_temp_mat'],'s');
            catch
                warning('Temporary directory could not be removed');
            end
            
            if strcmp(saveopt,'Yes')
                pewtr2=app.pewtr2;
                save([pathname filesep filename],'pewtr2','-v7.3');
                disp('Workspace successfully saved');
            end
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='on';
            disp('CDA complete');
            
            %                 catch
            %                     app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='on';
            %                     disp(['An error occurred during CDA - deconvolution ' ...
            %                         'unsuccessful for participant ' num2str(ii)]);
            %                 end
            p_displayrefresh(app,event);
            p_preprocessdisp(app);
            p_updatedisplay(app, event);
        end

        % Menu selected function: SaveworkspaceMenu
        function SaveworkspaceMenuSelected(app, event)
            pewtr2=app.pewtr2;
            uisave('pewtr2');
        end

        % Menu selected function: LoadworkspaceMenu
        function LoadworkspaceMenuSelected(app, event)
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='off';
            [filename,pathname]=uigetfile('*.mat','Select a PEWTR workspace file');
            if filename==0
                app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='on';
                return
            end
            pewtr2=load([pathname filesep filename],'pewtr2');
            app.pewtr2=pewtr2.pewtr2;
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='on';
            p_displayrefresh(app);
            p_preprocessdisp(app);
            p_updatedisplay(app);
        end

        % Button pushed function: FindACCpeaksButton
        function FindACCpeaksButtonPushed(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'acc');
            if sum(dd)==0 %check for ACC data
                errordlg(['No accelerometer data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.interlosscheck.Value=0;
                return
            end
            
            %Switch to ACC display mode
            app.DatatypeDropDown.Value='ACC';
            p_updatedisplay(app);
            
            %Prep for plotting
            displaytime=minutes(app.disptime.([app.DatatypeDropDown.Value 'mins']));
            displaytime.Format='hh:mm:ss';
            app.UIAxes.XLabel.String='Time (Recording Time)';
            [~,t1]=min(abs(displaytime-duration(app.StarttimeEditPlot.Value)));
            [~,t2]=min(abs(displaytime-duration(app.EndtimeEditPlot.Value)));
            displaydata=nansum(app.dispdat.(app.DatatypeDropDown.Value)(t1:t2,:),2);
            displaydata=(displaydata-min(displaydata))/(max(displaydata)-...
                min(displaydata)).^2;
            
            %plotting
            plot(app.UIAxes,displaytime(t1:t2),displaydata);
            app.UIAxes.ColorOrder=lines;
            
            %%after plotting
            %             set(app.UIAxes,'XTickMode','auto','XTickLabelMode','auto',...
            %                 'YTickMode','auto','YTickLabelMode','auto');
            
        end

        % Button pushed function: FromSelectionButton
        function FromSelectionButtonPushed(app, event)
            anc=duration(app.UIAxes.Children.Children.X);
            anc.Format='hh:mm:ss.SSS';
            app.AnchorEditField.Value=char(anc);
        end

        % Button pushed function: RealignButton
        function RealignButtonPushed(app, event)
            td=duration(app.AnchorEditField.Value)-duration(app.NewAnchorEditField.Value);
            td=minutes(td);
            app.pewtr2.process(:,9)={td};
            p_preprocessdisp(app);
            p_updatedisplay(app);
            FindACCpeaksButtonPushed(app,event);
            app.RealignButton.Visible=0;
            app.RevertButton.Visible=1;
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            app.pewtr2.process(:,9)={0};
            p_preprocessdisp(app);
            FindACCpeaksButtonPushed(app,event);
            app.RealignButton.Visible=1;
            app.RevertButton.Visible=0;
        end

        % Button pushed function: AddSegmentButton
        function AddSegmentButtonPushed(app, event)
            % NEEDS SAFEGUARDS
            T1=duration(app.SegmentStartEditField.Value);
            T2=duration(app.SegmentEndEditField.Value);
            Tx=[app.DatatypeDropDown.Value 'mins'];
            TT=app.ConditionNameEditField.Value;
            %Checks
            if T2<T1
                errordlg();
            end
            if T1<app.disptime.(Tx)(1) || T1>app.disptime.(Tx)(end)
                errordlg();
            end
            if T2<app.disptime.(Tx)(1) || T2>app.disptime.(Tx)(end)
                errordlg();
            end
            if isempty(TT)
                errordlg('Please enter a name for the current condition');
                return
            end
            
            if sum(strcmpi(TT,app.pewtr2.conditions(:,1)))==0
                app.pewtr2.conditions{end+1,1}=TT;
                app.pewtr2.conditions{end,2}=[T1 T2];
            else
                Tinx=find(strcmpi(TT,app.pewtr2.conditions(:,1))); %row
                Tinx2=find(~cellfun(@isempty, app.pewtr2.conditions(Tinx,:)),1,'last'); %+1 for col
                app.pewtr2.conditions(Tinx,Tinx2+1)={[T1 T2]}; %add window
            end
            
            %update listbox
            app.ConditionsListBox.Items={};
            for ii=1:length(app.pewtr2.conditions(:,1))
                if ii==1
                    app.ConditionsListBox.Items{1}='Whole session (default)';
                else
                    wins=sum(~cellfun(@isempty, app.pewtr2.conditions(ii,2:end)));
                    app.ConditionsListBox.Items=[app.ConditionsListBox.Items...
                        {[app.pewtr2.conditions{ii,1}...
                        ' (' num2str(wins) ' segments)']}];
                end
            end
            app.ConditionsListBox.ItemsData=1:ii;
            
        end

        % Button pushed function: RemoveSelectedConditionButton
        function RemoveSelectedConditionButtonPushed(app, event)
            dinx=app.ConditionsListBox.Value;
            if dinx==1
                return
            end
            app.pewtr2.conditions(dinx,:)=[];
            %update listbox
            app.ConditionsListBox.Items={};
            for ii=1:length(app.pewtr2.conditions(:,1))
                if ii==1
                    app.ConditionsListBox.Items{1}='Whole session (default)';
                else
                    wins=sum(~cellfun(@isempty, app.pewtr2.conditions(ii,2:end)));
                    app.ConditionsListBox.Items=[app.ConditionsListBox.Items...
                        {[app.pewtr2.conditions{ii,1}...
                        ' (' num2str(wins) ' segments)']}];
                end
            end
            app.ConditionsListBox.ItemsData=1:ii;
        end

        % Value changed function: ConditionsListBox
        function ConditionsListBoxValueChanged(app, event)
            p_updatedisplay(app);
        end

        % Value changed function: PlotSelectedConditionButton
        function PlotSelectedConditionButtonValueChanged(app, event)
            p_updatedisplay(app);
        end

        % Button pushed function: DefineGroupsButton
        function DefineGroupsButtonPushed(app, event)
            p_groups(app);
        end

        % Value changed function: GroupsListBox
        function GroupsListBoxValueChanged(app, event)
            
            
        end

        % Button pushed function: export
        function exportButtonPushed(app, event)
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='off';
            [filename,pathname,~]=uiputfile('*.mat','Export dataset as');
            %% Process EDA
            D=app.DatatypeDropDown.Items; %datatypes
            pewtr3=app.pewtr2;
            
            for dd=1:numel(D)
                
                if ~isempty(pewtr3.process{dd,4}) %if temp exclusion
                    pewtr3.process{dd,4} %debug
                    tt=strcmpi(pewtr3.process{dd,4}(1),...
                        fieldnames(pewtr3.data)); % find temp inx
                    
                    [pewtr3.data.(D{dd}),pewtr3.time.(D{dd}),...
                        pewtr3.time.([D{dd} 'mins']),...
                        pewtr3.process{dd,10}]= p_process(app,...
                        pewtr3.data.(D{dd}),pewtr3.time.(D{dd}),...
                        pewtr3.time.([D{dd} 'mins']),pewtr3.process(dd,:),...
                        pewtr3.file.Hz.([D{dd} 'hz']),pewtr3.data.(D{tt}));
                else
                    [pewtr3.data.(D{dd}),pewtr3.time.(D{dd}),...
                        pewtr3.time.([D{dd} 'mins']),...
                        pewtr3.process{dd,10}]= p_process(app,...
                        pewtr3.data.(D{dd}),pewtr3.time.(D{dd}),...
                        pewtr3.time.([D{dd} 'mins']),pewtr3.process(dd,:),...
                        pewtr3.file.Hz.([D{dd} 'hz']),[]);
                    
                end
            end

            save([pathname filesep filename],'pewtr3','-v7.3');
            disp([datestr(datetime('now')) ' - Export complete'])
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible='on';
        end

        % Value changed function: BWapply
        function BWapplyValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                app.DatatypeDropDown.Value='EDA';
                if app.BWapply.Value==1 %turned on
                    app.pewtr2.process{dt,3}={'Butter',app.butterorder.Value,...
                        app.butterfreq.Value};
                    
                    %switch off other filters
                    app.SGapply.Value=0;
                    
                else %turned off
                    app.pewtr2.process{dt,3}=[];
                    
                end
                p_preprocessdisp(app);
                p_updatedisplay(app);
            end
        end

        % Value changed function: SGwinEdit
        function SGwinEditValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                if app.SGapply.Value==1 %turned on
                    app.DatatypeDropDown.Value='EDA';
                    app.pewtr2.process{dt,3}={'SG',app.SGorderEdit.Value,...
                        app.SGwinEdit.Value};
                    p_preprocessdisp(app);
                    p_updatedisplay(app);
                end
                
            end
            
        end

        % Value changed function: SGorderEdit
        function SGorderEditValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                if app.SGapply.Value==1 %turned on
                    app.DatatypeDropDown.Value='EDA';
                    app.pewtr2.process{dt,3}={'SG',app.SGorderEdit.Value,...
                        app.SGwinEdit.Value};
                    p_preprocessdisp(app);
                    p_updatedisplay(app);
                end
                
            end
        end

        % Value changed function: butterfreq
        function butterfreqValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                if app.BWapply.Value==1 %turned on
                    app.DatatypeDropDown.Value='EDA';
                    app.pewtr2.process{dt,3}={'Butter',app.butterorder.Value,...
                        app.butterfreq.Value};
                    p_preprocessdisp(app);
                    p_updatedisplay(app);
                end
                
            end
        end

        % Value changed function: butterorder
        function butterorderValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Items,'EDA');
            if sum(dt)~=0
                if app.BWapply.Value==1 %turned on
                    app.DatatypeDropDown.Value='EDA';
                    app.pewtr2.process{dt,3}={'Butter',app.butterorder.Value,...
                        app.butterfreq.Value};
                    p_preprocessdisp(app);
                    p_updatedisplay(app);
                end
                
            end
        end

        % Value changed function: preCDA
        function preCDAValueChanged(app, event)
            if app.preCDA.Value==1
                app.postCDA.Value=0;
            else 
                app.postCDA.Value=1;
            end
            
        end

        % Value changed function: postCDA
        function postCDAValueChanged(app, event)
            if app.postCDA.Value==1
                app.preCDA.Value=0;
            else 
                app.preCDA.Value=1;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {643, 643, 643};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {643, 643};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x', 313};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure = uifigure('Visible', 'off');
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Name = 'Processing Environment for Wearable Technology Research';
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticipantsListBoxLabel = uilabel(app.LeftPanel);
            app.ParticipantsListBoxLabel.Text = 'Participants';
            app.ParticipantsListBox = uilistbox(app.LeftPanel);
            app.ParticipantsListBox.Multiselect = 'on';
            app.ParticipantsListBox.ValueChangedFcn = createCallbackFcn(app, @ParticipantsListBoxValueChanged, true);
            app.ParticipantsListBox.Value = {'Item 1'};
            app.MeanCheckBox = uicheckbox(app.LeftPanel);
            app.MeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanCheckBoxValueChanged, true);
            app.MeanCheckBox.Text = 'Mean';
            app.DisplayOptions = uitabgroup(app.LeftPanel);
            app.PlottingTab = uitab(app.DisplayOptions);
            app.PlottingTab.Title = 'Plotting';
            app.StarttimeEditField_2Label = uilabel(app.PlottingTab);
            app.StarttimeEditField_2Label.Text = 'Start time';
            app.StarttimeEditPlot = uieditfield(app.PlottingTab, 'text');
            app.EndtimeEditField_2Label = uilabel(app.PlottingTab);
            app.EndtimeEditField_2Label.Text = 'End time';
            app.EndtimeEditPlot = uieditfield(app.PlottingTab, 'text');
            app.TimePlotSwitch = uiswitch(app.PlottingTab, 'slider');
            app.TimePlotSwitch.Items = {'Default', 'Custom'};
            app.TimePlotSwitch.ValueChangedFcn = createCallbackFcn(app, @TimePlotSwitchValueChanged, true);
            app.TimePlotSwitch.Value = 'Default';
            app.AmpPlotSwitch = uiswitch(app.PlottingTab, 'slider');
            app.AmpPlotSwitch.Items = {'Default', 'Custom'};
            app.AmpPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @AmpPlotSwitchValueChanged, true);
            app.AmpPlotSwitch.Value = 'Default';
            app.AmpminLabel = uilabel(app.PlottingTab);
            app.AmpminLabel.Text = 'Amp min';
            app.AmpMinEditPlot = uieditfield(app.PlottingTab, 'text');
            app.AmpmaxLabel = uilabel(app.PlottingTab);
            app.AmpmaxLabel.Text = 'Amp max';
            app.AmpMaxEditPlot = uieditfield(app.PlottingTab, 'text');
            app.CropTimeTab = uitab(app.DisplayOptions);
            app.CropTimeTab.Title = 'Crop Time';
            app.CroptimeButton = uibutton(app.CropTimeTab, 'state');
            app.CroptimeButton.ValueChangedFcn = createCallbackFcn(app, @CroptimeButtonValueChanged, true);
            app.CroptimeButton.Text = 'Crop time';
            app.StarttimeEditFieldLabel = uilabel(app.CropTimeTab);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditCrop = uieditfield(app.CropTimeTab, 'text');
            app.EndtimeEditFieldLabel = uilabel(app.CropTimeTab);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditCrop = uieditfield(app.CropTimeTab, 'text');
            app.DatatypeDropDownLabel = uilabel(app.LeftPanel);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.LeftPanel);
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.ShowLegendCheckBox = uicheckbox(app.LeftPanel);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = {'Show'; 'Legend'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.EDAFilterTab = uitab(app.TabGroup);
            app.EDAFilterTab.Title = 'EDA Filter';
            app.LowpassButterworthFilterLabel = uilabel(app.EDAFilterTab);
            app.LowpassButterworthFilterLabel.Text = 'Low-pass Butterworth Filter';
            app.SavitzkyGolaySmoothingFilterLabel = uilabel(app.EDAFilterTab);
            app.SavitzkyGolaySmoothingFilterLabel.Text = 'Savitzky-Golay Smoothing Filter';
            app.OrderEditFieldLabel = uilabel(app.EDAFilterTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.butterorder = uieditfield(app.EDAFilterTab, 'numeric');
            app.butterorder.ValueChangedFcn = createCallbackFcn(app, @butterorderValueChanged, true);
            app.butterorder.Value = 1;
            app.LowpassFreqEditFieldLabel = uilabel(app.EDAFilterTab);
            app.LowpassFreqEditFieldLabel.Text = 'Lowpass Freq';
            app.butterfreq = uieditfield(app.EDAFilterTab, 'numeric');
            app.butterfreq.ValueChangedFcn = createCallbackFcn(app, @butterfreqValueChanged, true);
            app.butterfreq.Value = 0.6;
            app.PolynomialorderEditFieldLabel = uilabel(app.EDAFilterTab);
            app.PolynomialorderEditFieldLabel.Text = 'Polynomial order';
            app.SGorderEdit = uieditfield(app.EDAFilterTab, 'numeric');
            app.SGorderEdit.ValueChangedFcn = createCallbackFcn(app, @SGorderEditValueChanged, true);
            app.SGorderEdit.Value = 3;
            app.WindowsizesecsEditFieldLabel = uilabel(app.EDAFilterTab);
            app.WindowsizesecsEditFieldLabel.Text = 'Window size (secs)';
            app.SGwinEdit = uieditfield(app.EDAFilterTab, 'numeric');
            app.SGwinEdit.ValueChangedFcn = createCallbackFcn(app, @SGwinEditValueChanged, true);
            app.SGwinEdit.Value = 5;
            app.BWpreview = uibutton(app.EDAFilterTab, 'state');
            app.BWpreview.ValueChangedFcn = createCallbackFcn(app, @BWpreviewValueChanged, true);
            app.BWpreview.Text = 'Preview';
            app.BWapply = uibutton(app.EDAFilterTab, 'state');
            app.BWapply.ValueChangedFcn = createCallbackFcn(app, @BWapplyValueChanged, true);
            app.BWapply.Text = 'Apply';
            app.SGpreview = uibutton(app.EDAFilterTab, 'state');
            app.SGpreview.ValueChangedFcn = createCallbackFcn(app, @SGpreviewValueChanged, true);
            app.SGpreview.Text = 'Preview';
            app.SGapply = uibutton(app.EDAFilterTab, 'state');
            app.SGapply.ValueChangedFcn = createCallbackFcn(app, @SGapplyValueChanged, true);
            app.SGapply.Text = 'Apply';
            app.EDAArtifactsTab = uitab(app.TabGroup);
            app.EDAArtifactsTab.Title = 'EDA Artifacts';
            app.templabel = uilabel(app.EDAArtifactsTab);
            app.templabel.Text = 'Temperature Range';
            app.RangeCLabel = uilabel(app.EDAArtifactsTab);
            app.RangeCLabel.Text = 'Range (°C)';
            app.tempmax = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.tempmax.ValueChangedFcn = createCallbackFcn(app, @tempmaxValueChanged, true);
            app.tempmax.Value = 40;
            app.tempmin = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.tempmin.ValueChangedFcn = createCallbackFcn(app, @tempminValueChanged, true);
            app.tempmin.Value = 30;
            app.edalabel = uilabel(app.EDAArtifactsTab);
            app.edalabel.Text = 'EDA Range';
            app.edamin = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.edamin.ValueChangedFcn = createCallbackFcn(app, @edaminValueChanged, true);
            app.edamin.Value = 0.05;
            app.RangeSLabel_2 = uilabel(app.EDAArtifactsTab);
            app.RangeSLabel_2.Text = 'Range (µS)';
            app.edamax = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.edamax.ValueChangedFcn = createCallbackFcn(app, @edamaxValueChanged, true);
            app.edamax.Value = 60;
            app.MaxsignalchangeSsEditFieldLabel_2 = uilabel(app.EDAArtifactsTab);
            app.MaxsignalchangeSsEditFieldLabel_2.Text = 'Max signal change (μS/s)';
            app.jumpchange = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.jumpchange.ValueChangedFcn = createCallbackFcn(app, @jumpchangeValueChanged, true);
            app.jumpchange.Value = 10;
            app.SlopewindowmsLabel = uilabel(app.EDAArtifactsTab);
            app.SlopewindowmsLabel.Text = 'Slope window (ms)';
            app.jumpwin = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.jumpwin.ValueChangedFcn = createCallbackFcn(app, @jumpwinValueChanged, true);
            app.jumpwin.Value = 250;
            app.jumplabel = uilabel(app.EDAArtifactsTab);
            app.jumplabel.Text = 'Jump Artifacts';
            app.translabel = uilabel(app.EDAArtifactsTab);
            app.translabel.Text = 'Transitional Period';
            app.transtime = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.transtime.ValueChangedFcn = createCallbackFcn(app, @transtimeValueChanged, true);
            app.transtime.Value = 5;
            app.TimesecsLabel = uilabel(app.EDAArtifactsTab);
            app.TimesecsLabel.Text = 'Time (secs)';
            app.interlosslabel = uilabel(app.EDAArtifactsTab);
            app.interlosslabel.Text = 'Inter-loss period';
            app.interlossedit = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.interlossedit.ValueChangedFcn = createCallbackFcn(app, @interlosseditValueChanged, true);
            app.interlossedit.Value = 6;
            app.interlossslabel = uilabel(app.EDAArtifactsTab);
            app.interlossslabel.Text = 'Minimum epoch length (s)';
            app.tempselect = uidropdown(app.EDAArtifactsTab);
            app.tempselect.ValueChangedFcn = createCallbackFcn(app, @tempselectValueChanged, true);
            app.featuredist = uibutton(app.EDAArtifactsTab, 'push');
            app.featuredist.ButtonPushedFcn = createCallbackFcn(app, @featuredistButtonPushed, true);
            app.featuredist.Text = 'Plot data feature distribution';
            app.tempcheck = uibutton(app.EDAArtifactsTab, 'state');
            app.tempcheck.ValueChangedFcn = createCallbackFcn(app, @tempcheckValueChanged, true);
            app.tempcheck.Text = 'Off';
            app.edacheck = uibutton(app.EDAArtifactsTab, 'state');
            app.edacheck.ValueChangedFcn = createCallbackFcn(app, @edacheckValueChanged, true);
            app.edacheck.Text = 'Off';
            app.jumpcheck = uibutton(app.EDAArtifactsTab, 'state');
            app.jumpcheck.ValueChangedFcn = createCallbackFcn(app, @jumpcheckValueChanged, true);
            app.jumpcheck.Text = 'Off';
            app.transcheck = uibutton(app.EDAArtifactsTab, 'state');
            app.transcheck.ValueChangedFcn = createCallbackFcn(app, @transcheckValueChanged, true);
            app.transcheck.Text = 'Off';
            app.interlosscheck = uibutton(app.EDAArtifactsTab, 'state');
            app.interlosscheck.ValueChangedFcn = createCallbackFcn(app, @interlosscheckValueChanged, true);
            app.interlosscheck.Text = 'Off';
            app.dataloss = uibutton(app.EDAArtifactsTab, 'state');
            app.dataloss.ValueChangedFcn = createCallbackFcn(app, @datalossValueChanged, true);
            app.dataloss.Text = 'Plot Data Loss';
            app.entropy = uibutton(app.EDAArtifactsTab, 'state');
            app.entropy.ValueChangedFcn = createCallbackFcn(app, @entropyValueChanged, true);
            app.entropy.Text = 'Plot Shannon Entropy';
            app.AlignTimeseriesTab = uitab(app.TabGroup);
            app.AlignTimeseriesTab.Title = 'Align Timeseries';
            app.AnchorEditFieldLabel = uilabel(app.AlignTimeseriesTab);
            app.AnchorEditFieldLabel.Text = 'Anchor';
            app.AnchorEditField = uieditfield(app.AlignTimeseriesTab, 'text');
            app.AnchorEditField.Value = '00:00:00.000';
            app.RealignrecordedtimeLabel = uilabel(app.AlignTimeseriesTab);
            app.RealignrecordedtimeLabel.Text = 'Realign recorded time';
            app.FromSelectionButton = uibutton(app.AlignTimeseriesTab, 'push');
            app.FromSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FromSelectionButtonPushed, true);
            app.FromSelectionButton.Text = 'From Selection';
            app.NewAnchorEditFieldLabel = uilabel(app.AlignTimeseriesTab);
            app.NewAnchorEditFieldLabel.Text = 'New Anchor';
            app.NewAnchorEditField = uieditfield(app.AlignTimeseriesTab, 'text');
            app.NewAnchorEditField.Value = '00:00:00.000';
            app.RealignButton = uibutton(app.AlignTimeseriesTab, 'push');
            app.RealignButton.ButtonPushedFcn = createCallbackFcn(app, @RealignButtonPushed, true);
            app.RealignButton.Text = 'Realign';
            app.RevertButton = uibutton(app.AlignTimeseriesTab, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Visible = 'off';
            app.RevertButton.Text = 'Revert';
            app.FindACCpeaksButton = uibutton(app.AlignTimeseriesTab, 'push');
            app.FindACCpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindACCpeaksButtonPushed, true);
            app.FindACCpeaksButton.Text = 'Find ACC peaks';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.ConditionsListBoxLabel = uilabel(app.SegmentationTab);
            app.ConditionsListBoxLabel.Text = 'Conditions';
            app.ConditionsListBox = uilistbox(app.SegmentationTab);
            app.ConditionsListBox.Items = {'Whole session (default)'};
            app.ConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @ConditionsListBoxValueChanged, true);
            app.ConditionsListBox.Value = 'Whole session (default)';
            app.ConditionNameEditFieldLabel = uilabel(app.SegmentationTab);
            app.ConditionNameEditFieldLabel.Text = 'Condition Name';
            app.ConditionNameEditField = uieditfield(app.SegmentationTab, 'text');
            app.SegmentStartEditFieldLabel = uilabel(app.SegmentationTab);
            app.SegmentStartEditFieldLabel.Text = 'Segment Start';
            app.SegmentStartEditField = uieditfield(app.SegmentationTab, 'text');
            app.SegmentStartEditField.Value = '00:00:00.000';
            app.SegmentEndEditFieldLabel = uilabel(app.SegmentationTab);
            app.SegmentEndEditFieldLabel.Text = 'Segment End';
            app.SegmentEndEditField = uieditfield(app.SegmentationTab, 'text');
            app.SegmentEndEditField.Value = '00:00:00.000';
            app.AddSegmentButton = uibutton(app.SegmentationTab, 'push');
            app.AddSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddSegmentButtonPushed, true);
            app.AddSegmentButton.Text = 'Add Segment';
            app.RemoveSelectedConditionButton = uibutton(app.SegmentationTab, 'push');
            app.RemoveSelectedConditionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedConditionButtonPushed, true);
            app.RemoveSelectedConditionButton.Text = 'Remove Selected Condition';
            app.PlotSelectedConditionButton = uibutton(app.SegmentationTab, 'state');
            app.PlotSelectedConditionButton.ValueChangedFcn = createCallbackFcn(app, @PlotSelectedConditionButtonValueChanged, true);
            app.PlotSelectedConditionButton.Text = 'Plot Selected Condition';
            app.BetweenSubjectsFactorsTab = uitab(app.TabGroup);
            app.BetweenSubjectsFactorsTab.Title = 'Between-Subjects Factors';
            app.DefineGroupsButton = uibutton(app.BetweenSubjectsFactorsTab, 'push');
            app.DefineGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @DefineGroupsButtonPushed, true);
            app.DefineGroupsButton.Text = 'Define Groups';
            app.GroupsListBoxLabel = uilabel(app.BetweenSubjectsFactorsTab);
            app.GroupsListBoxLabel.Text = 'Groups';
            app.GroupsListBox = uilistbox(app.BetweenSubjectsFactorsTab);
            app.GroupsListBox.Items = {'None defined'};
            app.GroupsListBox.ValueChangedFcn = createCallbackFcn(app, @GroupsListBoxValueChanged, true);
            app.GroupsListBox.Value = 'None defined';
            app.DeconvolutionTab = uitab(app.TabGroup);
            app.DeconvolutionTab.Title = 'Deconvolution';
            app.SmoothingDropDownLabel = uilabel(app.DeconvolutionTab);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SmoothingDropDown = uidropdown(app.DeconvolutionTab);
            app.SmoothingDropDown.Items = {'None', 'Moving average', 'Hanning window', 'Gauss window', 'Adaptive Smoothing'};
            app.SmoothingDropDown.Value = 'None';
            app.InterfacewithLedalabLabel = uilabel(app.DeconvolutionTab);
            app.InterfacewithLedalabLabel.Text = 'Interface with Ledalab';
            app.SmoothingwindowwidthinsamplesEditFieldLabel = uilabel(app.DeconvolutionTab);
            app.SmoothingwindowwidthinsamplesEditFieldLabel.Text = 'Smoothing window width in samples';
            app.smoothwinedit = uieditfield(app.DeconvolutionTab, 'numeric');
            app.ledalabpush = uibutton(app.DeconvolutionTab, 'push');
            app.ledalabpush.ButtonPushedFcn = createCallbackFcn(app, @ledalabpushButtonPushed, true);
            app.ledalabpush.Tag = 'ledalabpush';
            app.ledalabpush.Text = 'Run CDA in Ledalab';
            app.Label = uilabel(app.DeconvolutionTab);
            app.Label.Text = 'Note: EDA filters will be applied prior to continuous deconvolution analysis';
            app.preCDA = uicheckbox(app.DeconvolutionTab);
            app.preCDA.ValueChangedFcn = createCallbackFcn(app, @preCDAValueChanged, true);
            app.preCDA.Text = 'Remove artifacts prior to CDA';
            app.preCDA.Value = true;
            app.postCDA = uicheckbox(app.DeconvolutionTab);
            app.postCDA.ValueChangedFcn = createCallbackFcn(app, @postCDAValueChanged, true);
            app.postCDA.Text = 'Remove artifacts after CDA';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.export = uibutton(app.ExportTab, 'push');
            app.export.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.export.Tag = 'export';
            app.export.Text = 'Apply & Export';
            app.Label_2 = uilabel(app.ExportTab);
            app.Label_2.Text = 'This will apply all current settings and export data ready for analysis';
            app.FileMenu = uimenu(app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportrawdataMenu = uimenu(app.FileMenu);
            app.ImportrawdataMenu.Text = 'Import raw data';
            app.EmpaticaformatMenu = uimenu(app.ImportrawdataMenu);
            app.EmpaticaformatMenu.MenuSelectedFcn = createCallbackFcn(app, @EmpaticaformatMenuSelected, true);
            app.EmpaticaformatMenu.Text = 'Empatica format';
            app.SaveworkspaceMenu = uimenu(app.FileMenu);
            app.SaveworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveworkspaceMenuSelected, true);
            app.SaveworkspaceMenu.Text = 'Save workspace';
            app.LoadworkspaceMenu = uimenu(app.FileMenu);
            app.LoadworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadworkspaceMenuSelected, true);
            app.LoadworkspaceMenu.Text = 'Load workspace';
            app.ProcessingMenu = uimenu(app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure);
            app.ProcessingMenu.Text = 'Processing';
            app.FilterMenu = uimenu(app.ProcessingMenu);
            app.FilterMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterMenuSelected, true);
            app.FilterMenu.Text = 'Filter';
            app.EDAArtifactsMenu = uimenu(app.ProcessingMenu);
            app.EDAArtifactsMenu.MenuSelectedFcn = createCallbackFcn(app, @EDAArtifactsMenuSelected, true);
            app.EDAArtifactsMenu.Text = 'EDA Artifacts';
            app.ManualArtifactsMenu = uimenu(app.ProcessingMenu);
            app.ManualArtifactsMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualArtifactsMenuSelected, true);
            app.ManualArtifactsMenu.Text = 'Manual Artifacts';
            app.AlignTimeseriesMenu = uimenu(app.ProcessingMenu);
            app.AlignTimeseriesMenu.MenuSelectedFcn = createCallbackFcn(app, @AlignTimeseriesMenuSelected, true);
            app.AlignTimeseriesMenu.Text = 'Align Timeseries';
            app.RepeatedMeasuresMenu = uimenu(app.ProcessingMenu);
            app.RepeatedMeasuresMenu.MenuSelectedFcn = createCallbackFcn(app, @RepeatedMeasuresMenuSelected, true);
            app.RepeatedMeasuresMenu.Text = 'Repeated Measures';
            app.GroupingMenu = uimenu(app.ProcessingMenu);
            app.GroupingMenu.MenuSelectedFcn = createCallbackFcn(app, @GroupingMenuSelected, true);
            app.GroupingMenu.Text = 'Grouping';
            % Show the figure after all components are created
            app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pewtrcon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcessingEnvironmentforWearableTechnologyResearchUIFigure)
        end
    end
end